classdef Project1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMProject1AppUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MakeStructureTab                matlab.ui.container.Tab
        HowtouseButton                  matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        ElementIndicatorSwitch          matlab.ui.control.ToggleSwitch
        ElementIndicatorSwitchLabel     matlab.ui.control.Label
        NodeIndicatorSwitch             matlab.ui.control.ToggleSwitch
        NodeIndicatorSwitchLabel        matlab.ui.control.Label
        AreamEditField                  matlab.ui.control.NumericEditField
        AreamEditFieldLabel             matlab.ui.control.Label
        ElasticModulusPaEditField       matlab.ui.control.NumericEditField
        ElasticModulusPaEditFieldLabel  matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Autumn_2                        matlab.ui.control.ToggleButton
        Parula_2                        matlab.ui.control.ToggleButton
        Bone_2                          matlab.ui.control.ToggleButton
        Jet_2                           matlab.ui.control.ToggleButton
        Lamp                            matlab.ui.control.Lamp
        EndingNode                      matlab.ui.control.NumericEditField
        EndingNodeEditFieldLabel        matlab.ui.control.Label
        StartingNode                    matlab.ui.control.NumericEditField
        StartingNodeEditFieldLabel      matlab.ui.control.Label
        AddingElementLabel              matlab.ui.control.Label
        AddingNodeLabel                 matlab.ui.control.Label
        X_coor                          matlab.ui.control.NumericEditField
        XCoordinateLabel                matlab.ui.control.Label
        Y_coor                          matlab.ui.control.NumericEditField
        YCoordinateLabel                matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        GenerateNodeButton              matlab.ui.control.Button
        ofNodesEditField                matlab.ui.control.NumericEditField
        ofNodesEditFieldLabel           matlab.ui.control.Label
        ofElementsEditField             matlab.ui.control.NumericEditField
        ofElementsEditFieldLabel        matlab.ui.control.Label
        ReadNLELButton                  matlab.ui.control.Button
        ClearStructureButton            matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Knob                            matlab.ui.control.DiscreteKnob
        NodeNumberSwitch                matlab.ui.control.ToggleSwitch
        NodeLabelSwitchLabel            matlab.ui.control.Label
        ElementNumberSwitch             matlab.ui.control.ToggleSwitch
        NodeLabelSwitch_2Label          matlab.ui.control.Label
        ApplyBCButton                   matlab.ui.control.Button
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel         matlab.ui.control.Label
        ForceDispSwitch                 matlab.ui.control.Switch
        BCTypeLabel                     matlab.ui.control.Label
        DirectionSwitch                 matlab.ui.control.Switch
        DirectionSwitchLabel            matlab.ui.control.Label
        NodeNumberEditField             matlab.ui.control.NumericEditField
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ExtendedNodeListTab             matlab.ui.container.Tab
        ENL_Table                       matlab.ui.control.Table
        ExtendedNodeListLabel           matlab.ui.control.Label
        ElementListLabel                matlab.ui.control.Label
        EL_Table                        matlab.ui.control.Table
        NodeListLabel                   matlab.ui.control.Label
        NL_Table                        matlab.ui.control.Table
        SeeResultsTab                   matlab.ui.container.Tab
        OldNodeIndicatorSwitch          matlab.ui.control.RockerSwitch
        OldNodeIndicatorSwitchLabel     matlab.ui.control.Label
        NodeIndicatorSwitch_2           matlab.ui.control.RockerSwitch
        NodeIndicatorSwitch_2Label      matlab.ui.control.Label
        NodeNumberSwitch_2              matlab.ui.control.RockerSwitch
        NodeNumberSwitch_2Label         matlab.ui.control.Label
        ElementNumberSwitch_2           matlab.ui.control.RockerSwitch
        ElementNumberSwitch_2Label      matlab.ui.control.Label
        DisplacementDropDown            matlab.ui.control.DropDown
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        Autumn_3                        matlab.ui.control.ToggleButton
        Parula_3                        matlab.ui.control.ToggleButton
        Bone_3                          matlab.ui.control.ToggleButton
        Jet_3                           matlab.ui.control.ToggleButton
        SimplifiedRealisticExaggeratedAreyouseriousLabel  matlab.ui.control.Label
        ExaggerateSlider                matlab.ui.control.Slider
        ExaggerateSliderLabel           matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        OptimizationUserInterfaceTab    matlab.ui.container.Tab
        HowtouseButton_2                matlab.ui.control.Button
        TotalWeightEditField            matlab.ui.control.NumericEditField
        TotalWeightLabel                matlab.ui.control.Label
        ElasticModulusPaEditField_2     matlab.ui.control.NumericEditField
        ElasticModulusPaLabel           matlab.ui.control.Label
        AreamEditField_2                matlab.ui.control.NumericEditField
        AreamLabel                      matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        DensityLabel                    matlab.ui.control.Label
        ElementIndicatorSwitch_2        matlab.ui.control.RockerSwitch
        ElementIndicatorSwitch_2Label   matlab.ui.control.Label
        NodeIndicatorSwitch_3           matlab.ui.control.RockerSwitch
        NodeIndicatorSwitch_3Label      matlab.ui.control.Label
        ElementNumberSwitch_3           matlab.ui.control.RockerSwitch
        ElementNumberSwitch_3Label      matlab.ui.control.Label
        NodeNumberSwitch_3              matlab.ui.control.RockerSwitch
        NodeNumberSwitch_3Label         matlab.ui.control.Label
        ReadFileButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        LearnTheLogicBehindButton       matlab.ui.control.Button
        ScoreBasedIterationSwitch       matlab.ui.control.ToggleSwitch
        ScoreBasedIterationSwitchLabel  matlab.ui.control.Label
        ScoreGauge                      matlab.ui.control.SemicircularGauge
        ScoreEditField                  matlab.ui.control.NumericEditField
        ScoreLabel                      matlab.ui.control.Label
        TotalWeightStepbystepEditField  matlab.ui.control.NumericEditField
        TotalWeightGauge                matlab.ui.control.SemicircularGauge
        TotalWeightGaugeLabel           matlab.ui.control.Label
        MaximumDeflectionGauge          matlab.ui.control.SemicircularGauge
        MaximumDeflectionGaugeLabel     matlab.ui.control.Label
        MaximumDeflectionEditField      matlab.ui.control.NumericEditField
        IterationsDoneGauge             matlab.ui.control.SemicircularGauge
        IterationsDoneGaugeLabel        matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        MaxIterationNumberEditField     matlab.ui.control.NumericEditField
        MaxIterationNumberEditFieldLabel  matlab.ui.control.Label
        IterationNumberField            matlab.ui.control.NumericEditField
        OptimizeBridgeButton            matlab.ui.control.Button
        FixHorizontalSwitch             matlab.ui.control.ToggleSwitch
        FixHorizontalSwitchLabel        matlab.ui.control.Label
        MergeCloseNodesShowStressDistributionButton  matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        MeshedStructureAnalysisTab      matlab.ui.container.Tab
        HowtouseButton_3                matlab.ui.control.Button
        DisplacementDropDown_2          matlab.ui.control.DropDown
        FORCEEditField                  matlab.ui.control.NumericEditField
        FORCELabel                      matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        BeamWithNoVoidTab               matlab.ui.container.Tab
        Beam_dEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel_4               matlab.ui.control.Label
        Beam_cEditField                 matlab.ui.control.NumericEditField
        cEditFieldLabel_4               matlab.ui.control.Label
        Beam_bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel_4               matlab.ui.control.Label
        Beam_aEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel_4               matlab.ui.control.Label
        BeamShear                       matlab.ui.control.Button
        BeamExpansion                   matlab.ui.control.Button
        BeamExtension                   matlab.ui.control.Button
        BeamPlotButton                  matlab.ui.control.Button
        CircleTab                       matlab.ui.container.Tab
        CircleShear                     matlab.ui.control.Button
        CircleExpansion                 matlab.ui.control.Button
        CircleExtension                 matlab.ui.control.Button
        Circle_rEditField               matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        Circle_dEditField               matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        Circle_cEditField               matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        Circle_bEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        Circle_aEditField               matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        CirclePlotButton                matlab.ui.control.Button
        SquareTab                       matlab.ui.container.Tab
        Square_eEditField               matlab.ui.control.NumericEditField
        eLabel                          matlab.ui.control.Label
        Square_dEditField               matlab.ui.control.NumericEditField
        dEditFieldLabel_2               matlab.ui.control.Label
        Square_cEditField               matlab.ui.control.NumericEditField
        cEditFieldLabel_2               matlab.ui.control.Label
        Square_bEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel_2               matlab.ui.control.Label
        Square_aEditField               matlab.ui.control.NumericEditField
        aEditFieldLabel_2               matlab.ui.control.Label
        SquareShear                     matlab.ui.control.Button
        SquareExpansion                 matlab.ui.control.Button
        SquareExtension                 matlab.ui.control.Button
        SquarePlotButton                matlab.ui.control.Button
        RhombusTab                      matlab.ui.container.Tab
        Rhombus_eEditField              matlab.ui.control.NumericEditField
        eLabel_2                        matlab.ui.control.Label
        Rhombus_dEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel_3               matlab.ui.control.Label
        Rhombus_cEditField              matlab.ui.control.NumericEditField
        cEditFieldLabel_3               matlab.ui.control.Label
        Rhombus_bEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel_3               matlab.ui.control.Label
        Rhombus_aEditField              matlab.ui.control.NumericEditField
        aEditFieldLabel_3               matlab.ui.control.Label
        RhombusShear                    matlab.ui.control.Button
        RhombusExpansion                matlab.ui.control.Button
        RhombusExtension                matlab.ui.control.Button
        RhombusPlotButton               matlab.ui.control.Button
        AreamEditField_3                matlab.ui.control.NumericEditField
        AreamEditField_3Label           matlab.ui.control.Label
        ElasticModulusPaEditField_3     matlab.ui.control.NumericEditField
        ElasticModulusPaEditField_3Label  matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        Autumn_4                        matlab.ui.control.ToggleButton
        Parula_4                        matlab.ui.control.ToggleButton
        Bone_4                          matlab.ui.control.ToggleButton
        Jet_4                           matlab.ui.control.ToggleButton
        ofNodesEditField_2              matlab.ui.control.NumericEditField
        ofNodesEditField_2Label         matlab.ui.control.Label
        ofElementsEditField_2           matlab.ui.control.NumericEditField
        ofElementsEditField_2Label      matlab.ui.control.Label
        ClearStructureButton_2          matlab.ui.control.Button
        ApplyBCButton_2                 matlab.ui.control.Button
        MagnitudeEditField_2            matlab.ui.control.NumericEditField
        MagnitudeEditField_2Label       matlab.ui.control.Label
        ForceDispSwitch_2               matlab.ui.control.Switch
        BCTypeLabel_2                   matlab.ui.control.Label
        DirectionSwitch_2               matlab.ui.control.Switch
        DirectionSwitch_2Label          matlab.ui.control.Label
        NodeNumberEditField_2           matlab.ui.control.NumericEditField
        NodeNumberEditField_2Label      matlab.ui.control.Label
        RUNButton_2                     matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        UIAxes_9                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        NoN_A = []
        NL_A = []
        ENL_A = []
        NoE_A = []
        EL_A = []
        nl_A = []
        DOFs_A
        DOCs_A
        Fp_A = []
        Up_A = []
        Uu_A = []
        Fu_A = []

        Void = 0
        Force13 = 500
        Global_p = 0


        ForceConverted = []
        NeuBCLaterUsed = []
        ENL1112afteroptimizationused = []
        ENL_wait = []
        NL_RecordedX = []
        NL_RecordedY = []

        ForceX = 0
        ForceAmount = 0


        NL
        EL
        NoN
        NoE
        NodeSize = 25
        
        ENL = []
        ENLFirst = []
        ENLfirst1112 = []
        UseOpt2 = []
        ENLTemp = []
        
        DispDrop = "Total Displacement"
        DispDrop_2 = "Total Displacement"

        Score_Based = "On"


        DOFs 
        DOCs 

        FixHorizontal = "On";

        Uniform_a
        Uniform_b
        Uniform_c
        Uniform_d

        Circle_a 
        Circle_b 
        Circle_c 
        Circle_d 
        Circle_r 
        
       
        Up = []
        Fp = []
        Fu = []
        Uu = []
        nl = []

        Up2 = []
        Fp2 = []
        Fu2 = []
        Uu2 = []
        nl2 = []

        Up3 = []
        Fp3 = []
        Fu3 = []
        Uu3 = []
        NoN3

        TotalW = 0;
        TotalW_A = 0;

        A = 0.01;
        E = 10^6;
        ro = 3.5;

        Knob_2value = "Tiny";
        
        ElementSwitch_2 = 'Off';
        NodeSwitch_2 = 'Off';
        NodeIndicator_2 = "Off";
        NodeIndicatorNO = "Off";
        mag = 25;

        BCvalue = 0
        BCvalue13 = 0
        NodeNumber = 0
        NodeNumber13 = 0
        BCforcedisplacementSwitch = "D"
        BCforcedisplacementSwitch13 = "D"
        BCdirectionSwitch = "X"
        BCdirectionSwitch13 = "X"
        NodeSwitch = 'On'
        ElementSwitch = "On"
        NodeIndicator = "On"
        ElementIndicator = "On"

        OPTNodeSwitch = 'Off'
        OPTElementSwitch = "Off"
        OPTNodeIndicator = "Off"
        OPTElementIndicator = "Off"

        colorMap = jet
        
    end
    
    methods (Access = public)


        function results = updateAxes(app)

            cla(app.UIAxes);

            hold(app.UIAxes,'on');

            colormap(app.UIAxes,app.colorMap);
            colorbar(app.UIAxes);


            for i=1 : app.NoE
                H = plot(app.UIAxes,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',10,'Color','k');
                H.Color(4) = 0.2;

            end
            
            switch app.NodeIndicator
                case "On"
                    app.Lamp_3.Color = "g";
                    for i=1 : app.NoN
                        plot(app.UIAxes,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','g')
                    end
                case "Off"
                    app.Lamp_3.Color = "k";
            end
            

            switch app.ElementIndicator
                case "On"
                    app.Lamp_4.Color = "g";
                    for i=1 : app.NoE
                        plot(app.UIAxes,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2, 's','MarkerSize',(app.NodeSize+5),'MarkerEdgeColor','red','MarkerFaceColor','#FF4500'); 
                    end
                case "Off"     
                    app.Lamp_4.Color = "k";
            end

            switch app.NodeSwitch
                case 'On'
                    app.Lamp.Color = "g";
                    for i = 1:app.NoN
                        text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),'Color','k','FontSize',15,'HorizontalAlignment','center')
                    end
                case "Off"
                    app.Lamp.Color = "k";
                    
            end
            
            switch app.ElementSwitch
                case 'On'
                    app.Lamp_2.Color = "g";
                    for i = 1:app.NoE
                        text(app.UIAxes,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2,num2str(i),'Color','w','FontSize',15,'HorizontalAlignment','center')
                    end
                case "Off"
                    app.Lamp_2.Color = "k";


            end

        end


        function results = OPTupdateAxes(app)

            cla(app.UIAxes_4);
            cla(app.UIAxes_5);

            hold(app.UIAxes_4,'on');
            hold(app.UIAxes_5,'on');

            %colormap(app.UIAxes_4,app.colorMap);
            %colorbar(app.UIAxes_4);
            
            
            for i=1 : app.NoE
                H = plot(app.UIAxes_4,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',7,'Color','k');
                HH = plot(app.UIAxes_5,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',7,'Color','k');
                H.Color(4) = 0.2;
                HH.Color(4) = 0.2;

            end
            
            switch app.OPTNodeIndicator
                case "On" 
                    for i=1 : app.NoN
                        plot(app.UIAxes_4,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize-2,'MarkerEdgeColor','k','MarkerFaceColor','g')
                        plot(app.UIAxes_5,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize-2,'MarkerEdgeColor','k','MarkerFaceColor','g')
                    end
                case "Off"
                    
            end
            

            switch app.OPTElementIndicator
                case "On"
                    
                    for i=1 : app.NoE
                        plot(app.UIAxes_4,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2, 's','MarkerSize',(app.NodeSize-2),'MarkerEdgeColor','red','MarkerFaceColor','#FF4500'); 
                        plot(app.UIAxes_5,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2, 's','MarkerSize',(app.NodeSize-2),'MarkerEdgeColor','red','MarkerFaceColor','#FF4500'); 
                    end
                case "Off"     
                    
            end

            switch app.OPTNodeSwitch
                case 'On'
                    
                    for i = 1:app.NoN
                        text(app.UIAxes_4,app.NL(i,1),app.NL(i,2),num2str(i),'Color','k','FontSize',15,'HorizontalAlignment','center')
                        text(app.UIAxes_5,app.NL(i,1),app.NL(i,2),num2str(i),'Color','k','FontSize',15,'HorizontalAlignment','center')
                    end
                case "Off"
                    
                    
            end
            
            switch app.OPTElementSwitch
                case 'On'
                    
                    for i = 1:app.NoE
                        text(app.UIAxes_4,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2,num2str(i),'Color','w','FontSize',15,'HorizontalAlignment','center')
                        text(app.UIAxes_5,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2,num2str(i),'Color','w','FontSize',15,'HorizontalAlignment','center')
                    end
                case "Off"

            end

            hold(app.UIAxes_4,'on');
            hold(app.UIAxes_5,'on')
            
            X1 = [0 -10 -10 0];
            Y1 = [0 0 -10 -10];
            patch(app.UIAxes_4,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_5,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)

            endnodex = max(app.NL(:,1));
        

            X2 = [endnodex+10 endnodex endnodex endnodex+10];
            Y2 = [0 0 -10 -10];
            patch(app.UIAxes_4,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_5,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)

            X3 = [0 endnodex endnodex 0];
            Y3 = [-3 -3 -10 -10];
            patch(app.UIAxes_4,X3,Y3, 'b',"FaceAlpha",.5)
            patch(app.UIAxes_5,X3,Y3, 'b',"FaceAlpha",.5)

            X4 = [0 endnodex endnodex 0];
            Y4 = [-3 -3 -10 -10];
            patch(app.UIAxes_4,X4,Y4, "c" ,"FaceAlpha",.5)
            patch(app.UIAxes_5,X4,Y4, "c" ,"FaceAlpha",.5)

            axis(app.UIAxes_4,[-1 endnodex+1 -8 7.5]);
            axis(app.UIAxes_5,[-1 endnodex+1 -8 7.5]);


        end


        function assign_BCs2(app)

            
            app.NoN = size(app.NL,1);
            PD = size(app.NL,2);
            
            if size(app.ENL,2) ~= 12

                app.ENL = zeros(app.NoN, 12);
            end

            app.ENL = app.ENL(1:size(app.NL,1),:);

            app.ENL(:,1:2) = app.NL;


            hold(app.UIAxes,'on');
           
            if app.NodeNumber ~= 0
                switch app.BCdirectionSwitch
                    case 'X'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                hold(app.UIAxes,'on');
                                app.ENL(app.NodeNumber,11) = app.BCvalue;
                                app.ENL(app.NodeNumber,3) = 1;
                                if app.BCvalue > 0
                                    plot(app.UIAxes,linspace(app.NL(app.NodeNumber,1)+0.1,(app.NL(app.NodeNumber,1)+0.5),5),app.NL(app.NodeNumber,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)+0.6,app.NL(app.NodeNumber,2),num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                elseif app.BCvalue < 0
                                    plot(app.UIAxes,linspace(app.NL(app.NodeNumber,1)-0.1,(app.NL(app.NodeNumber,1)-0.5),5),app.NL(app.NodeNumber,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.65,app.NL(app.NodeNumber,2),num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                end
                            case 'D'
                                hold(app.UIAxes,'on');
                                if app.BCvalue == 0

                                    app.ENL(app.NodeNumber,9) = app.BCvalue;
                                    app.ENL(app.NodeNumber,3) = -1;
                                    hold(app.UIAxes,'on');
                                    xp = [app.NL(app.NodeNumber,1) (app.NL(app.NodeNumber,1)-0.25) (app.NL(app.NodeNumber,1)-0.25)];
                                    yp = [app.NL(app.NodeNumber,2) (app.NL(app.NodeNumber,2)+0.125) (app.NL(app.NodeNumber,2)-0.125)];
                                    patch(app.UIAxes,xp, yp, 'w', "FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber,9) = app.BCvalue;
                                    app.ENL(app.NodeNumber,3) = -1;
                                end

                        end

                    case 'Y'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                app.ENL(app.NodeNumber,12) = app.BCvalue;
                                app.ENL(app.NodeNumber,4) = 1;
                                if app.BCvalue > 0
                                    plot(app.UIAxes,app.NL(app.NodeNumber,1),linspace(app.NL(app.NodeNumber,2)+0.1,app.NL(app.NodeNumber,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.03,app.NL(app.NodeNumber,2)+0.6,num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                elseif app.BCvalue < 0
                                    plot(app.UIAxes,app.NL(app.NodeNumber,1),linspace(app.NL(app.NodeNumber,2)-0.1,app.NL(app.NodeNumber,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.03,app.NL(app.NodeNumber,2)-0.6,num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                end

                            case 'D'
                                hold(app.UIAxes,'on');
                                if app.BCvalue == 0

                                    app.ENL(app.NodeNumber,10) = app.BCvalue;
                                    app.ENL(app.NodeNumber,4) = -1;
                                    hold(app.UIAxes,'on');
                                    xp2 = [app.NL(app.NodeNumber,1) (app.NL(app.NodeNumber,1)-0.125) (app.NL(app.NodeNumber,1)+0.125)];
                                    yp2 = [app.NL(app.NodeNumber,2) (app.NL(app.NodeNumber,2)-0.25) (app.NL(app.NodeNumber,2)-0.25)];
                                    patch(app.UIAxes,xp2, yp2, 'w',"FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber,10) = app.BCvalue;
                                    app.ENL(app.NodeNumber,4) = -1;
                                end


                        end
                end
            end


            app.DOFs = 0;
            app.DOCs = 0;
            
            for i = 1:app.NoN
                for j = 1:PD
                    if (app.ENL(i,(PD+j)) == -1)
            
                        app.DOCs = app.DOCs - 1;
                        app.ENL(i, 2*PD+j) = app.DOCs;
                    else
                        app.DOFs = app.DOFs + 1;
                        app.ENL(i, 2*PD+j) = app.DOFs;
                    end
                end
            end
            
            
            for i = 1:app.NoN
                for j= 1:PD
                    if (app.ENL(i,(2*PD+j)) < 0 )
            
                        app.ENL(i, 3*PD+j) = app.DOFs + abs(app.ENL(i,(2*PD+j)));
                    else
                       
                        app.ENL(i, 3*PD+j) = app.ENL(i,2*PD+j);
                    end
                end
            end
            
            app.DOCs = abs(app.DOCs);
            
        end


         function assign_BCs13(app)

            
            app.NoN = size(app.NL,1);
            PD = size(app.NL,2);
            
            if size(app.ENL,2) ~= 12

                app.ENL = zeros(app.NoN, 12);
            end

            app.ENL = app.ENL(1:size(app.NL,1),:);

            app.ENL(:,1:2) = app.NL;


            hold(app.UIAxes_9,'on');
           
            if app.NodeNumber13 ~= 0
                switch app.BCdirectionSwitch13
                    case 'X'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                hold(app.UIAxes_9,'on');
                                app.ENL(app.NodeNumber13,11) = app.BCvalue13;
                                app.ENL(app.NodeNumber13,3) = 1;
                                if app.BCvalue13 > 0
                                    plot(app.UIAxes_9,linspace(app.NL(app.NodeNumber13,1)+0.1,(app.NL(app.NodeNumber13,1)+0.5),5),app.NL(app.NodeNumber13,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes_9,app.NL(app.NodeNumber13,1)+0.6,app.NL(app.NodeNumber13,2),num2str(app.BCvalue13) + " N",'Color','y','FontSize',10,'FontWeight','bold')
                                elseif app.BCvalue13 < 0
                                    plot(app.UIAxes_9,linspace(app.NL(app.NodeNumber13,1)-0.1,(app.NL(app.NodeNumber13,1)-0.5),5),app.NL(app.NodeNumber13,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes_9,app.NL(app.NodeNumber13,1)-0.65,app.NL(app.NodeNumber13,2),num2str(app.BCvalue13) + " N",'Color','y','FontSize',10,'FontWeight','bold')
                                end
                            case 'D'
                                hold(app.UIAxes_9,'on');
                                if app.BCvalue13 == 0

                                    app.ENL(app.NodeNumber13,9) = app.BCvalue13;
                                    app.ENL(app.NodeNumber13,3) = -1;
                                    hold(app.UIAxes_9,'on');
                                    xp = [app.NL(app.NodeNumber13,1) (app.NL(app.NodeNumber13,1)-0.25) (app.NL(app.NodeNumber13,1)-0.25)];
                                    yp = [app.NL(app.NodeNumber13,2) (app.NL(app.NodeNumber13,2)+0.125) (app.NL(app.NodeNumber13,2)-0.125)];
                                    patch(app.UIAxes_9,xp, yp, 'w', "FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber13,9) = app.BCvalue13;
                                    app.ENL(app.NodeNumber13,3) = -1;
                                end

                        end

                    case 'Y'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                app.ENL(app.NodeNumber13,12) = app.BCvalue13;
                                app.ENL(app.NodeNumber13,4) = 1;
                                if app.BCvalue13 > 0
                                    plot(app.UIAxes_9,app.NL(app.NodeNumber13,1),linspace(app.NL(app.NodeNumber13,2)+0.1,app.NL(app.NodeNumber13,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes_9,app.NL(app.NodeNumber13,1)-0.03,app.NL(app.NodeNumber13,2)+0.6,num2str(app.BCvalue13) + " N",'Color','y','FontSize',10,'FontWeight','bold')
                                elseif app.BCvalue13 < 0
                                    plot(app.UIAxes_9,app.NL(app.NodeNumber13,1),linspace(app.NL(app.NodeNumber13,2)-0.1,app.NL(app.NodeNumber13,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes_9,app.NL(app.NodeNumber13,1)-0.03,app.NL(app.NodeNumber13,2)-0.6,num2str(app.BCvalue13) + " N",'Color','y','FontSize',10,'FontWeight','bold')
                                end

                            case 'D'
                                hold(app.UIAxes_9,'on');
                                if app.BCvalue13 == 0

                                    app.ENL(app.NodeNumber13,10) = app.BCvalue13;
                                    app.ENL(app.NodeNumber13,4) = -1;
                                    hold(app.UIAxes_9,'on');
                                    xp2 = [app.NL(app.NodeNumber13,1) (app.NL(app.NodeNumber13,1)-0.125) (app.NL(app.NodeNumber13,1)+0.125)];
                                    yp2 = [app.NL(app.NodeNumber13,2) (app.NL(app.NodeNumber13,2)-0.25) (app.NL(app.NodeNumber13,2)-0.25)];
                                    patch(app.UIAxes_9,xp2, yp2, 'w',"FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber13,10) = app.BCvalue13;
                                    app.ENL(app.NodeNumber13,4) = -1;
                                end


                        end
                end
            end


            app.DOFs = 0;
            app.DOCs = 0;
            
            for i = 1:app.NoN
                for j = 1:PD
                    if (app.ENL(i,(PD+j)) == -1)
            
                        app.DOCs = app.DOCs - 1;
                        app.ENL(i, 2*PD+j) = app.DOCs;
                    else
                        app.DOFs = app.DOFs + 1;
                        app.ENL(i, 2*PD+j) = app.DOFs;
                    end
                end
            end
            
            
            for i = 1:app.NoN
                for j= 1:PD
                    if (app.ENL(i,(2*PD+j)) < 0 )
            
                        app.ENL(i, 3*PD+j) = app.DOFs + abs(app.ENL(i,(2*PD+j)));
                    else
                       
                        app.ENL(i, 3*PD+j) = app.ENL(i,2*PD+j);
                    end
                end
            end
            
            app.DOCs = abs(app.DOCs);
            
        end


        function k = element_stiffness2(app)
            X(1) = app.ENL(app.nl(1),1);
            Y(1) = app.ENL(app.nl(1),2);
            X(2) = app.ENL(app.nl(2),1);
            Y(2) = app.ENL(app.nl(2),2);
            
            L = sqrt((X(1)-X(2))^2 + (Y(1)-Y(2))^2);
            
            C = (X(2)-X(1))/L;
            S = (Y(2)-Y(1))/L;
            
            k = ((app.A*app.E)/L) * [C^2 C*S -C^2 -C*S
                                     C*S S^2 -C*S -S^2
                                     -C^2 -C*S C^2 C*S
                                     -C*S -S^2 C*S S^2];
        end

        function k = element_stiffnessOPT2(app)
            X(1) = app.ENL(app.nl(1),1);
            Y(1) = app.ENL(app.nl(1),2);
            X(2) = app.ENL(app.nl(2),1);
            Y(2) = app.ENL(app.nl(2),2);
            
            L = sqrt((X(1)-X(2))^2 + (Y(1)-Y(2))^2);
            
            C = (X(2)-X(1))/L;
            S = (Y(2)-Y(1))/L;
            
            k = ((app.A*app.E)/L) * [C^2 C*S -C^2 -C*S
                                     C*S S^2 -C*S -S^2
                                     -C^2 -C*S C^2 C*S
                                     -C*S -S^2 C*S S^2];
        end

        function [K] = assemble_stiffness2(app)

                app.NoE = size(app.EL,1);
                NPE = size(app.NL,2);
                
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                K = zeros(app.NoN*PD, app.NoN*PD);


                for i = 1:app.NoE
                
                    app.nl = app.EL(i, 1:NPE);


                    k = element_stiffness2(app);
                
                    for r = 1:NPE 
                        for p = 1:PD
                            for q = 1:NPE
                                for s = 1:PD
                
                                    row = app.ENL(app.nl(r), p+3*PD);
                                    column = app.ENL(app.nl(q),s+3*PD);
                
                                    value = k((r-1)*PD+p, (q-1)*PD+s);
                                    K(row,column) = K(row,column) + value;
                
                                end
                            end
                        end
                    end
                end
                
            end


            function assemble_forces2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOFs = 0;
                app.Fp = [];
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.Fp(app.DOFs,1) = app.ENL(i,5*PD+j);
                
                        end
                    end
                end
            end
        
        
            function assemble_displacements2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOCs = 0;
                app.Up = [];
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.Up(app.DOCs,1) = app.ENL(i,4*PD+j);
                       
                        end
                    end
                end
            end


            function assemble_forces3(app)
    
                app.NoN3 = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOFs = 0;
                app.Fp3 = [];
                
                for i = 1:app.NoN3
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.Fp3(app.DOFs,1) = app.ENL(i,5*PD+j);
                
                        end
                    end
                end
            end
        
        
            function assemble_displacements3(app)
    
                app.NoN3 = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOCs = 0;
                app.Up3 = [];
                
                for i = 1:app.NoN3
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.Up3(app.DOCs,1) = app.ENL(i,4*PD+j);
                       
                        end
                    end
                end
            end

            function assemble_forcesOPT2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOFs = 0;
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.Fp2(app.DOFs,1) = app.ENL(i,5*PD+j);
                
                        end
                    end
                end
            end
        
        
            function assemble_displacementsOPT2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                app.DOCs = 0;
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.Up2(app.DOCs,1) = app.ENL(i,4*PD+j);
                       
                        end
                    end
                end
            end


            function [K] = assemble_stiffnessOPT2(app)

                app.NoE = size(app.EL,1);
                NPE = size(app.NL,2);
                
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                
                K = zeros(app.NoN*PD, app.NoN*PD);
                
                
                for i = 1:app.NoE
                
                    app.nl = app.EL(i, 1:NPE);


                    k = element_stiffnessOPT2(app);
                
                    for r = 1:NPE 
                        for p = 1:PD
                            for q = 1:NPE
                                for s = 1:PD
                
                                    row = app.ENL(app.nl(r), p+3*PD);
                                    column = app.ENL(app.nl(q),s+3*PD);
                
                                    value = k((r-1)*PD+p, (q-1)*PD+s);
                                    K(row,column) = K(row,column) + value;
                
                                end
                            end
                        end
                    end
                end
                
            end


            function update_nodes2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                app.DOFs = 0;
                app.DOCs = 0;
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.ENL(i,4*PD+j) = app.Uu(app.DOFs);
                         end
                    end
                end
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.ENL(i,5*PD+j) = app.Fu(app.DOCs);
                
                        end
                    end
                end
            end

            function update_nodes3(app)
    
                app.NoN3 = size(app.NL,1);
                PD = size(app.NL,2);
                app.DOFs = 0;
                app.DOCs = 0;
                
                for i = 1:app.NoN3
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.ENL(i,4*PD+j) = app.Uu3(app.DOFs);
                         end
                    end
                end
                
                for i = 1:app.NoN3
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.ENL(i,5*PD+j) = app.Fu3(app.DOCs);
                
                        end
                    end
                end
            end


            function update_nodesOPT2(app)
    
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);
                app.DOFs = 0;
                app.DOCs = 0;

                app.DOCs
                app.DOFs
                PD
                app.NoN
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.ENL(i,4*PD+j) = app.Uu2(app.DOFs);
                         end
                    end
                end
                
                for i = 1:app.NoN
                    for j = 1:PD
                        if app.ENL(i, PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.ENL(i,5*PD+j) = app.Fu2(app.DOCs);
                
                        end
                    end
                end
            end


            function post_process2(app)
    
                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                
                %%%%%  Draw Undeformed Configuration  %%%%%%
                
                cla(app.UIAxes_8);

                hold(app.UIAxes_2,'on');
    
                colormap(app.UIAxes_2,app.colorMap);


                hold(app.UIAxes_8,'on');
    
                colormap(app.UIAxes_8,app.colorMap);
                

                cla(app.UIAxes_3);

                hold(app.UIAxes_3,'on');
    
                colormap(app.UIAxes_3,app.colorMap);

                cla(app.UIAxes_2);


                for i=1 : app.NoE
                    H = plot(app.UIAxes_2,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H1 = plot(app.UIAxes_3,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H.Color(4) = 0.2;
                    H1.Color(4) = 0.2;
                
                end
                
                %%%%%%  Plot Nodes  %%%%%%
                
      
                switch app.NodeIndicatorNO
                    case "On"
                        
                        for i=1 : app.NoN
                            plot(app.UIAxes_2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            plot(app.UIAxes_3,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            
                        end
                    case "Off"
                        
                end


                %%%%%   Draw The Deformed Configuration   %%%%%


                %%%%%   Plot Elements With Stress Distribution   %%%%%
                
                for i=1:app.NoE
                
                    x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                    y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                    z = zeros(size(x));
                
                    if app.DispDrop == "Total Displacement"
                        TotalDispCol = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                        surface(app.UIAxes_3, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol','interp','linew',4);
%                         surface(app.UIAxes_8, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol','interp','linew',4);
                    
                    elseif app.DispDrop == "X Displacement"
                        
                        XDispCol = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                        surface(app.UIAxes_3, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);
                        surface(app.UIAxes_8, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);
                        
                    elseif app.DispDrop == "Y Displacement"   
                        
                        YDispCol = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                        surface(app.UIAxes_3, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);
                        surface(app.UIAxes_8, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);
                    end


                    sigmaS = app.E *(sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2+(app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                    colS = [sigmaS,sigmaS];
              
                    %colD = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    

                    surface(app.UIAxes_8,[x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol', 'interp','linew', 4);
                    surface(app.UIAxes_2,[x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol', 'interp','linew', 4);
                    %surface(app.UIAxes_3,[x;x],[y;y],[z;z],[colD;colD],'facecol','no','edgecol', 'interp','linew', 4);
                end
                
%                 axis equal
                 colorbar(app.UIAxes_2, "Color","w");
                 colorbar(app.UIAxes_3, "Color","w");
                 colorbar(app.UIAxes_8, "Color","w");


                %%%%%   Plot Element Numbers   %%%%%
                
               
                switch app.ElementSwitch_2
                case 'On'
                   
                    for i=1:app.NoE
                        text(app.UIAxes_2,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/2,(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/2,num2str(i),'Color','w','FontSize',22,'HorizontalAlignment','center')
                        text(app.UIAxes_3,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/2,(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/2,num2str(i),'Color','w','FontSize',22,'HorizontalAlignment','center')
                    end
                case "Off"
                    
                end


                %%%%%   Plot Nodes   %%%%%
                
                
                 switch app.NodeIndicator_2
                 case "On"
                   
                    for i=1:app.NoN
                    plot(app.UIAxes_2,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),'o','MarkerSize',16,'MarkerEdgeColor','g','MarkerFaceColor',[0,0.7,0.9])
                    plot(app.UIAxes_3,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),'o','MarkerSize',16,'MarkerEdgeColor','g','MarkerFaceColor',[0,0.7,0.9])
                    end
                 case "Off"
                    
                 end


                %%%%%   Plot Node Numbers   %%%%%
                
          
                switch app.NodeSwitch_2
                case 'On'
                    for i = 1:app.NoN
                        text(app.UIAxes_2,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        text(app.UIAxes_3,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                    end
                case "Off"
                    
               
                end

                hold(app.UIAxes_8,'on');
            
            
                X1 = [0 -10 -10 0];
                Y1 = [0 0 -10 -10];
                patch(app.UIAxes_8,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
                
    
                endnodex = max(app.NL(:,1));
            
    
                X2 = [endnodex+10 endnodex endnodex endnodex+10];
                Y2 = [0 0 -10 -10];
                patch(app.UIAxes_8,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
                
    
                X3 = [0 endnodex endnodex 0];
                Y3 = [-3 -3 -10 -10];
                patch(app.UIAxes_8,X3,Y3, 'b',"FaceAlpha",.5)
                
    
                X4 = [0 endnodex endnodex 0];
                Y4 = [-3 -3 -10 -10];
                patch(app.UIAxes_8,X4,Y4, "c" ,"FaceAlpha",.5)
    
    
                axis(app.UIAxes_8,[-1 endnodex+1 -8 7.5]);

            end


            function post_process_FirstOptimization(app)
    
                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                app.ENL(:,2) = app.ENL(:,2)*(-1);
                
                %%%%%  Draw Undeformed Configuration  %%%%%%
                
                cla(app.UIAxes_6);

                hold(app.UIAxes_6,'on');
    
                colormap(app.UIAxes_6,app.colorMap);


%                 for i=1 : app.NoE
%                     
%                     H1 = plot(app.UIAxes_6,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
%                     
%                     H1.Color(4) = 0.2;
%                 
%                 end


                %%%%%  Plot Nodes  %%%%%%
                
      
                switch app.NodeIndicatorNO
                    case "On"
                        
                        for i=1 : app.NoN
                            
                            plot(app.UIAxes_6,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor','r')
                        end
                    case "Off"
                        
                end


                %%%%%   Draw The Deformed Configuration   %%%%%


                %%%%%   Plot Elements With Stress Distribution   %%%%%

                app.ENLFirst = zeros(app.NoN, 12);

                app.ENLFirst(:,1) = (app.ENL(:,1) + app.ENL(:,9))*(1);
                app.ENLFirst(:,2) = (app.ENL(:,2) + app.ENL(:,10))*(-1);
                app.ENLFirst(:,3:8) = app.ENL(:,3:8);
                app.ENLFirst(:,11:12) = app.ENLfirst1112(:,:);
                
                app.ENL
                app.ENLFirst


            for i=1:app.NoE

                x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                y = [(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10))*(-1) (app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))*(-1)];
                z = zeros(size(x));

                app.NL_RecordedX(i,:) = zeros(1,2);
                app.NL_RecordedX(i,:) = x;
                app.NL_RecordedY(i,:) = zeros(1,2);
                app.NL_RecordedY(i,:) = y;

                if app.DispDrop == "Total Displacement"
                    TotalDispCol = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    surface(app.UIAxes_6, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol',[.3 .3 .3],'linew',4);

                elseif app.DispDrop == "X Displacement"

                    XDispCol = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                    surface(app.UIAxes_6, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);

                elseif app.DispDrop == "Y Displacement"

                    YDispCol = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                    surface(app.UIAxes_6, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);
                end


            end

            %                 axis equal

            %colorbar(app.UIAxes_6, "Color","w");


            %%%%%   Plot Element Numbers   %%%%%


            switch app.ElementSwitch_2
                case 'On'

                    for i=1:app.NoE

                        text(app.UIAxes_6,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/(2),(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/(-2),num2str(i),'Color','w','FontSize',22,'HorizontalAlignment','center')
                    end
                case "Off"

            end


            %%%%%   Plot Nodes   %%%%%


            switch app.NodeIndicator_2
                case "On"

                    for i=1:app.NoN

                        plot(app.UIAxes_6,(app.ENL(i,1)+app.mag*app.ENL(i,9))*(1),(app.ENL(i,2)+app.mag*app.ENL(i,10))*(-1),'o','MarkerSize',16,'MarkerEdgeColor','g','MarkerFaceColor',[0,0.7,0.9])
                    end
                case "Off"

            end


            %%%%%   Plot Node Numbers   %%%%%


            switch app.NodeSwitch_2
                case 'On'
                    for i = 1:app.NoN

                        text(app.UIAxes_6,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10)*(-1),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                    end
                case "Off"


            end


            hold(app.UIAxes_6,'on')

            X1 = [0 -10 -10 0];
            Y1 = [0 0 -10 -10];
            patch(app.UIAxes_6,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)


            endnodex = max(app.NL(:,1));


            X2 = [endnodex+10 endnodex endnodex endnodex+10];
            Y2 = [0 0 -10 -10];

            patch(app.UIAxes_6,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)

            X3 = [0 endnodex endnodex 0];
            Y3 = [-3 -3 -10 -10];

            patch(app.UIAxes_6,X3,Y3, 'b',"FaceAlpha",.5)

            X4 = [0 endnodex endnodex 0];
            Y4 = [-3 -3 -10 -10];

            patch(app.UIAxes_6,X4,Y4, "c" ,"FaceAlpha",.5)


            axis(app.UIAxes_6,[-1 endnodex+1 -8 7.5]);


            end


            function post_process_SecondOptimization(app)

%                 app.ENL(:,1:2) = app.NL;
% 
%                 app.ENL
%                
%                 app.NoN = size(app.NL,1);
%                 app.NoE = size(app.EL,1);
%                 app.ENL(:,2) = app.ENL(:,2)*(-1);
%                 
%                 %%%%%  Draw Undeformed Configuration  %%%%%%
%                 
%                 cla(app.UIAxes_7);
% 
%                 hold(app.UIAxes_7,'on');
%     
%                 colormap(app.UIAxes_7,app.colorMap);
%                 
% 
% 
%                 
%                 
% %                 for i=1 : app.NoE
% %                     
% %                     H1 = plot(app.UIAxes_7,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
% %                     
% %                     H1.Color(4) = 0.2;
% %                 
% %                 end
%                 
%                 %%%%%%  Plot Nodes  %%%%%%
%                 
%       
%                 switch app.NodeIndicatorNO
%                     case "On"
%                         
%                         for i=1 : app.NoN
%                             
%                             plot(app.UIAxes_7,app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor','r')
%                         end
%                     case "Off"
%                         
%                 end
% 
% 
%                 
%                 %%%%%   Draw The Deformed Configuration   %%%%%
%                 
%               
%                 
%                 %%%%%   Plot Elements With Stress Distribution   %%%%%
% 
% %                 app.ENL
% 
% %                 app.ENLFirst = zeros(app.NoN, 12);
% % 
% %                 app.ENLFirst(:,1) = (app.ENL(:,1) + app.ENL(:,9))*(1);
% %                 app.ENLFirst(:,2) = (app.ENL(:,2) + app.ENL(:,10))*(-1);
% %                 app.ENLFirst(:,3:8) = app.ENL(:,3:8);
% %                 app.ENLFirst(:,11:12) = app.ENLfirst1112(:,:);
% %                 
% %                 app.ENL
% %                 app.ENLFirst
% 
%                 
%                 
%             for i=1:app.NoE
% 
%                 x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
%                 y = [(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10))*(-1) (app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))*(-1)];
%                 z = zeros(size(x));
% 
%                 if app.DispDrop == "Total Displacement"
%                     TotalDispCol = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
%                     surface(app.UIAxes_7, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol',[.3 .3 .3],'linew',4);
% 
%                 elseif app.DispDrop == "X Displacement"
% 
%                     XDispCol = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
%                     surface(app.UIAxes_7, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);
% 
%                 elseif app.DispDrop == "Y Displacement"
% 
%                     YDispCol = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
%                     surface(app.UIAxes_7, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);
%                 end
% 
% 
%             end
% 
%             %                 axis equal
% 
%             %colorbar(app.UIAxes_6, "Color","w");
% 
% 
%             %%%%%   Plot Element Numbers   %%%%%
% 
% 
%             switch app.ElementSwitch_2
%                 case 'On'
% 
%                     for i=1:app.NoE
% 
%                         text(app.UIAxes_7,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/(2),(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/(-2),num2str(i),'Color','w','FontSize',22,'HorizontalAlignment','center')
%                     end
%                 case "Off"
% 
%             end
% 
% 
% 
%             %%%%%   Plot Nodes   %%%%%
% 
% 
%             switch app.NodeIndicator_2
%                 case "On"
% 
%                     for i=1:app.NoN
% 
%                         plot(app.UIAxes_7,(app.ENL(i,1)+app.mag*app.ENL(i,9))*(1),(app.ENL(i,2)+app.mag*app.ENL(i,10))*(-1),'o','MarkerSize',16,'MarkerEdgeColor','g','MarkerFaceColor',[0,0.7,0.9])
%                     end
%                 case "Off"
% 
%             end
% 
% 
% 
% 
%             %%%%%   Plot Node Numbers   %%%%%
% 
% 
%             switch app.NodeSwitch_2
%                 case 'On'
%                     for i = 1:app.NoN
% 
%                         text(app.UIAxes_7,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10)*(-1),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
%                     end
%                 case "Off"
% 
% 
%             end
% 
% 
%             hold(app.UIAxes_7,'on')
% 
%             X1 = [0 -10 -10 0];
%             Y1 = [0 0 -10 -10];
%             patch(app.UIAxes_7,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
% 
% 
%             endnodex = max(app.NL(:,1));
% 
% 
%             X2 = [endnodex+10 endnodex endnodex endnodex+10];
%             Y2 = [0 0 -10 -10];
% 
%             patch(app.UIAxes_7,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
% 
%             X3 = [0 endnodex endnodex 0];
%             Y3 = [-3 -3 -10 -10];
% 
%             patch(app.UIAxes_7,X3,Y3, 'b',"FaceAlpha",.5)
% 
%             X4 = [0 endnodex endnodex 0];
%             Y4 = [-3 -3 -10 -10];
% 
%             patch(app.UIAxes_7,X4,Y4, "c" ,"FaceAlpha",.5)
% 
% 
%             axis(app.UIAxes_7,[-1 endnodex+1 -8 7.5]);
% 
% 
            cla(app.UIAxes_7);
            

            hold(app.UIAxes_7,'on');
            

            %colormap(app.UIAxes_4,app.colorMap);
            %colorbar(app.UIAxes_4);
            
            
            for i=1 : app.NoE
                H = plot(app.UIAxes_7,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',5,'Color','k');
                
                H.Color(4) = 0.3;
                

            end
            
            switch app.OPTNodeIndicator
                case "On" 
                    for i=1 : app.NoN
                        plot(app.UIAxes_7,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize-2,'MarkerEdgeColor','k','MarkerFaceColor','g')
                        
                    end
                case "Off"
                    
            end
            

            switch app.OPTElementIndicator
                case "On"
                    
                    for i=1 : app.NoE
                        plot(app.UIAxes_7,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2, 's','MarkerSize',(app.NodeSize-2),'MarkerEdgeColor','red','MarkerFaceColor','#FF4500'); 
                         
                    end
                case "Off"     
                    
            end

            switch app.OPTNodeSwitch
                case 'On'
                    
                    for i = 1:app.NoN
                        text(app.UIAxes_7,app.NL(i,1),app.NL(i,2),num2str(i),'Color','k','FontSize',15,'HorizontalAlignment','center')
                        
                    end
                case "Off"
                    
                    
            end
            
            switch app.OPTElementSwitch
                case 'On'
                    
                    for i = 1:app.NoE
                        text(app.UIAxes_7,[app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1)]/2 ,[app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2)]/2,num2str(i),'Color','w','FontSize',15,'HorizontalAlignment','center')
                        
                    end
                case "Off"

            end

            hold(app.UIAxes_7,'on');
            
            
            X1 = [0 -10 -10 0];
            Y1 = [0 0 -10 -10];
            patch(app.UIAxes_7,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            

            endnodex = max(app.NL(:,1));
        

            X2 = [endnodex+10 endnodex endnodex endnodex+10];
            Y2 = [0 0 -10 -10];
            patch(app.UIAxes_7,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            

            X3 = [0 endnodex endnodex 0];
            Y3 = [-3 -3 -10 -10];
            patch(app.UIAxes_7,X3,Y3, 'b',"FaceAlpha",.5)
            

            X4 = [0 endnodex endnodex 0];
            Y4 = [-3 -3 -10 -10];
            patch(app.UIAxes_7,X4,Y4, "c" ,"FaceAlpha",.5)


            axis(app.UIAxes_7,[-1 endnodex+1 -8 7.5]);


            hold(app.UIAxes_8,'on');
            
            
            X1 = [0 -10 -10 0];
            Y1 = [0 0 -10 -10];
            patch(app.UIAxes_8,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            

            endnodex = max(app.NL(:,1));
        

            X2 = [endnodex+10 endnodex endnodex endnodex+10];
            Y2 = [0 0 -10 -10];
            patch(app.UIAxes_8,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            

            X3 = [0 endnodex endnodex 0];
            Y3 = [-3 -3 -10 -10];
            patch(app.UIAxes_8,X3,Y3, 'b',"FaceAlpha",.5)
            

            X4 = [0 endnodex endnodex 0];
            Y4 = [-3 -3 -10 -10];
            patch(app.UIAxes_8,X4,Y4, "c" ,"FaceAlpha",.5)


            axis(app.UIAxes_8,[-1 endnodex+1 -8 7.5]);


            end


            function findweight(app)

                 
                app.ENL
                app.EL
                for i = 1:size(app.EL,1)
                    
                    Node1 = app.EL(i,1);
                    Node2 = app.EL(i,2);


                    ElLength = sqrt((app.ENL(Node1,1)-app.ENL(Node2,1))^2+(app.ENL(Node1,2)-app.ENL(Node2,2))^2);

                    Elweight = app.A * ElLength * app.ro * (-9.81);

                    app.TotalW = app.TotalW + Elweight;

                    app.ENL(Node1,12) = app.ENL(Node1,12) + (Elweight/2);
                    
                    app.ENL(Node2,12) = app.ENL(Node2,12) + (Elweight/2);


                end

            end


            function findweight_A(app)


                app.TotalW_A = 0;

                for i = 1:size(app.EL_A,1)
                    
                    Node1 = app.EL_A(i,1);
                    Node2 = app.EL_A(i,2);


                    ElLength = sqrt((app.ENL_A(Node1,1)-app.ENL_A(Node2,1))^2+(app.ENL_A(Node1,2)-app.ENL_A(Node2,2))^2);

                    Elweight = app.A * ElLength * app.ro * (-9.81);

                    app.TotalW_A = app.TotalW_A + Elweight;
                    
%                     app.ENL_A(:,12) = app.ENL_A(:,12) + (app.ForceConverted);


                end

            end


            function findweight_Modified(app)

                 
                app.ENL
                app.EL
                for i = 1:size(app.EL,1)
                    
                    Node1 = app.EL(i,1);
                    Node2 = app.EL(i,2);


                    ElLength = sqrt((app.ENL(Node1,1)-app.ENL(Node2,1))^2+(app.ENL(Node1,2)-app.ENL(Node2,2))^2);

                    Elweight = app.A * ElLength * app.ro * (-9.81);

                    app.TotalW = app.TotalW + Elweight;

                    app.ENL(:,12) = app.ENL(:,12) + (app.ForceConverted);
                    

                end
            end


            function plotForceOPT(app)
                for j = 1:size(app.ENL,1)
                    for i = app.ENL(j,11)
                        if i > 0
                            plot(app.UIAxes_4,linspace(app.NL(j,1)+0.1,(app.NL(j,1)+0.5),5),app.NL(j,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_4,app.NL(j,1)+0.6,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
%                             app.IterationNumberField.Value = i;  %YANLIŞ%

                            plot(app.UIAxes_5,linspace(app.NL(j,1)+0.1,(app.NL(j,1)+0.5),5),app.NL(j,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_5,app.NL(j,1)+0.6,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                            
                        elseif i < 0
                            plot(app.UIAxes_4,linspace(app.NL(j,1)-0.1,(app.NL(j,1)-0.5),5),app.NL(j,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_4,app.NL(j,1)-0.65,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
%                             app.IterationNumberField.Value = i;

                            plot(app.UIAxes_5,linspace(app.NL(j,1)-0.1,(app.NL(j,1)-0.5),5),app.NL(j,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_5,app.NL(j,1)-0.65,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                            
                        end
                    end

                    for i = app.ENL(j,12)
                        if i > 0
                            plot(app.UIAxes_4,app.NL(j,1),linspace(app.NL(j,2)+0.1,app.NL(j,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_4,app.NL(j,1)+0.1,app.NL(j,2)+0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
%                             app.IterationNumberField.Value = i;

                            plot(app.UIAxes_5,app.NL(j,1),linspace(app.NL(j,2)+0.1,app.NL(j,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_5,app.NL(j,1)+0.1,app.NL(j,2)+0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                        elseif i < 0
                            plot(app.UIAxes_4,app.NL(j,1),linspace(app.NL(j,2)-0.1,app.NL(j,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_4,app.NL(j,1)+0.1,app.NL(j,2)-0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
%                             app.IterationNumberField.Value = i;

                            plot(app.UIAxes_5,app.NL(j,1),linspace(app.NL(j,2)-0.1,app.NL(j,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","y","MarkerFaceColor",[.96 .99 .45])
                            text(app.UIAxes_5,app.NL(j,1)+0.1,app.NL(j,2)-0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                        end
                    end
                end
            end


            function assign_BCs_OPTIMIZATION(app)

            
                app.NoN = size(app.NL,1);
                PD = size(app.NL,2);

                if size(app.ENL,2) ~= 12

                    app.ENL = zeros(app.NoN, 12);
                end

                app.ENL = app.ENL(1:size(app.NL,1),:);

                app.ENL(:,1:2) = app.NL;


                hold(app.UIAxes,'on');


                app.DOFs = 0;
                app.DOCs = 0;

                for i = 1:app.NoN
                    for j = 1:PD
                        if (app.ENL(i,(PD+j)) == -1)

                            app.DOCs = app.DOCs - 1;
                            app.ENL(i, 2*PD+j) = app.DOCs;
                        else
                            app.DOFs = app.DOFs + 1;
                            app.ENL(i, 2*PD+j) = app.DOFs;
                        end
                    end
                end


                for i = 1:app.NoN
                    for j= 1:PD
                        if (app.ENL(i,(2*PD+j)) < 0 )

                            app.ENL(i, 3*PD+j) = app.DOFs + abs(app.ENL(i,(2*PD+j)));
                        else

                            app.ENL(i, 3*PD+j) = app.ENL(i,2*PD+j);
                        end
                    end
                end

                app.DOCs = abs(app.DOCs);

            end

            function forceconverter(app)

                FixNode1 = 1;
                FixNode2 = 0;

                for i = 2:app.NoN
                    if app.ENL(i,3:4) == [-1 -1]
                        FixNode2 = i;
                    end
                end
                
                app.ENLTemp = app.ENL;

                app.ENL(1:FixNode2,4) = -1;
                app.ENL(1,3:4) = 1;
                app.ENL(FixNode2, 3:4) = 1;


                format long


                assign_BCs2(app); %write [enl dofsd docs]
                
                ENL3 = app.ENL(:,3);
                ENL4 = app.ENL(:,4);
                ENL3(ENL3==0)=1;
                ENL4(ENL4==0)=1;
                app.ENL(:,3) = ENL3;
                app.ENL(:,4) = ENL4;
    
                K = assemble_stiffness2(app);

                app.ENL


                assemble_forces2(app);
                assemble_displacements2(app);
                
                Kpu = K(1:app.DOFs, 1:app.DOFs);
                Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
                Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
                Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);
    
             
                F = app.Fp - Kpp*app.Up;
                
                app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
                
                app.Fu = Kuu*app.Uu + Kup*app.Up;
                
                update_nodes2(app);


                app.ENLTemp(1:app.NoN,11:12) = zeros(app.NoN,2);
                app.ENLTemp(1:FixNode2,11:12) = app.ENL(1:FixNode2,11:12)*(-1);

%                 ClearStructureButtonPushed(app);

                app.ENL = app.ENLTemp;

                app.ENL_Table.Data = app.ENL;

                app.ForceConverted = app.ENL(:,12) / 500;


            end

            function Analyzer(app)


                format long


                app.NoN_A = size(app.NL_A,1);
                PD = 2;
                
                if size(app.ENL_A,2) ~= 12
    
                    app.ENL_A = zeros(app.NoN_A, 12);
                end
    
                app.ENL_A = app.ENL_A(1:size(app.NL_A,1),:);
    
                app.ENL_A(:,1:2) = app.NL_A;


                app.DOFs_A = 0;
                app.DOCs_A = 0;
                
                for i = 1:app.NoN_A
                    for j = 1:PD
                        if (app.ENL_A(i,(PD+j)) == -1)
                
                            app.DOCs_A = app.DOCs_A - 1;
                            app.ENL_A(i, 2*PD+j) = app.DOCs_A;
                        else
                            app.DOFs_A = app.DOFs_A + 1;
                            app.ENL_A(i, 2*PD+j) = app.DOFs_A;
                        end
                    end
                end
                
                
                for i = 1:app.NoN_A
                    for j= 1:PD
                        if (app.ENL_A(i,(2*PD+j)) < 0 )
                
                            app.ENL_A(i, 3*PD+j) = app.DOFs_A + abs(app.ENL_A(i,(2*PD+j)));
                        else
                           
                            app.ENL_A(i, 3*PD+j) = app.ENL_A(i,2*PD+j);
                        end
                    end
                end
                
                app.DOCs_A = abs(app.DOCs_A);

                
                ENL3 = app.ENL_A(:,3);
                ENL4 = app.ENL_A(:,4);
                ENL3(ENL3==0)=1;
                ENL4(ENL4==0)=1;
                app.ENL_A(:,3) = ENL3;
                app.ENL_A(:,4) = ENL4;

                function [K] = assemble_stiffness_A(app)

                    app.NoE_A = size(app.EL_A,1);
                    NPE = size(app.NL_A,2);
                    
                    app.NoN_A = size(app.NL_A,1);
                    PD = 2;
                    
                    K = zeros(app.NoN_A*PD, app.NoN_A*PD);


                    for i = 1:app.NoE_A
                    
                        app.nl_A = app.EL_A(i, 1:NPE);


                        k = element_stiffness_A(app);
                    
                        for r = 1:NPE 
                            for p = 1:PD
                                for q = 1:NPE
                                    for s = 1:PD
                    
                                        row = app.ENL_A(app.nl_A(r), p+3*PD);
                                        column = app.ENL_A(app.nl_A(q),s+3*PD);
                    
                                        value = k((r-1)*PD+p, (q-1)*PD+s);
                                        K(row,column) = K(row,column) + value;
                    
                                    end
                                end
                            end
                        end
                    end
                
                end

                function k = element_stiffness_A(app)
                    
                    X(1) = app.ENL_A(app.nl_A(1),1);
                    Y(1) = app.ENL_A(app.nl_A(1),2);
                    X(2) = app.ENL_A(app.nl_A(2),1);
                    Y(2) = app.ENL_A(app.nl_A(2),2);
                    
                    L = sqrt((X(1)-X(2))^2 + (Y(1)-Y(2))^2);
                    
                    C = (X(2)-X(1))/L;
                    S = (Y(2)-Y(1))/L;
                    
                    k = ((app.A*app.E)/L) * [C^2 C*S -C^2 -C*S
                                             C*S S^2 -C*S -S^2
                                             -C^2 -C*S C^2 C*S
                                             -C*S -S^2 C*S S^2];
                end

                K = assemble_stiffness_A(app);

                function assemble_forces_A(app)
    
                    app.NoN_A = size(app.NL_A,1);
                    PD = 2;
                    
                    app.DOFs_A = 0;
                    app.Fp_A = [];
                    
                    for i = 1:app.NoN_A
                        for j = 1:PD
                            if app.ENL_A(i, PD+j) == 1
                    
                                app.DOFs_A = app.DOFs_A +1;
                                app.Fp_A(app.DOFs_A,1) = app.ENL_A(i,5*PD+j);
                    
                            end
                        end
                    end
                end
        
        
                function assemble_displacements_A(app)
        
                    app.NoN_A = size(app.NL_A,1);
                    PD = 2;
                    
                    app.DOCs_A = 0;
                    app.Up_A = [];
                    
                    for i = 1:app.NoN_A
                        for j = 1:PD
                            if app.ENL_A(i, PD+j) == -1
                    
                                app.DOCs_A = app.DOCs_A +1;
                                app.Up_A(app.DOCs_A,1) = app.ENL_A(i,4*PD+j);
                           
                            end
                        end
                    end
                end

                assemble_forces_A(app);
                assemble_displacements_A(app);
                
                Kpu = K(1:app.DOFs_A, 1:app.DOFs_A);
                Kpp = K(1:app.DOFs_A, app.DOFs_A+1:app.DOFs_A+app.DOCs_A);
                Kuu = K(app.DOFs_A+1:app.DOCs_A+app.DOFs_A, 1:app.DOFs_A);
                Kup = K(app.DOFs_A+1:app.DOCs_A+app.DOFs_A,app.DOFs_A+1:app.DOCs_A+app.DOFs_A);

                F = app.Fp_A - Kpp*app.Up_A;
            
                app.Uu_A = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
                
                app.Fu_A = Kuu*app.Uu_A + Kup*app.Up_A;


                function update_nodes_A(app)
    
                    app.NoN_A = size(app.NL_A,1);
                    PD = 2;
                    app.DOFs_A = 0;
                    app.DOCs_A = 0;
                    
                    for i = 1:app.NoN_A
                        for j = 1:PD
                            if app.ENL_A(i, PD+j) == 1
                    
                                app.DOFs_A = app.DOFs_A +1;
                                app.ENL_A(i,4*PD+j) = app.Uu_A(app.DOFs_A);
                             end
                        end
                    end
                    
                    for i = 1:app.NoN_A
                        for j = 1:PD
                            if app.ENL_A(i, PD+j) == -1
                    
                                app.DOCs_A = app.DOCs_A +1;
                                app.ENL_A(i,5*PD+j) = app.Fu_A(app.DOCs_A);
                    
                            end
                        end
                    end
                end

                update_nodes_A(app)

            end


            function Plotter(app)

                app.NoN = 0;
                app.NoE = 0;


                app.NL = [0 0];
                for i = 1:size(app.EL,1)
            
                    if sum(ismember([app.NL_RecordedX(i,1) app.NL_RecordedY(i,1)],app.NL,"rows")) == 0
                        app.NL(size(app.NL,1)+1,1:2) = zeros(1,2);
                        app.NL(size(app.NL,1),1) = app.NL_RecordedX(i,1);
                        app.NL(size(app.NL,1),2) = app.NL_RecordedY(i,1);
                
                    else
                
                    end


                    if sum(ismember([app.NL_RecordedX(i,2) app.NL_RecordedY(i,2)],app.NL,"rows")) == 0
                        app.NL(size(app.NL,1)+1,1:2) = zeros(1,2);
                        app.NL(size(app.NL,1),1) = app.NL_RecordedX(i,2);
                        app.NL(size(app.NL,1),2) = app.NL_RecordedY(i,2);
               
                    else
                
                    end
                end


%                 for f = 1 : size(app.ENL,1)
%                     if app.ENLfirst1112(f,2) == sum(app.ENLfirst1112(:,2))
%                         app.ForceX = app.ENL(f,1);
%                         app.ForceAmount = sum(app.ENLfirst1112(:,2));
%                     end
%                 end
%         
%                 app.ENL = zeros(size(app.NL,1),12);
%                 app.ENL(:,1:2) = app.NL;
%                 for z = 1 : size(app.ENL,1)
%         
%                     if app.ENL(:,1)-app.ForceX < 0.2
%                         app.ENL(z,12) = app.ForceAmount;
%                     end
%                 end


                post_process2(app)


            end


            function UniformMesher(app)

                app.NodeSize = 11;

                app.Uniform_a = app.Beam_aEditField.Value;
                app.Uniform_b = app.Beam_bEditField.Value;
                app.Uniform_c = app.Beam_cEditField.Value;
                app.Uniform_d = app.Beam_dEditField.Value;
                
                
                PD = 2;
                
                q = [0 0; app.Uniform_a 0; 0 app.Uniform_b; app.Uniform_a app.Uniform_b];
                
                app.NoN = (app.Uniform_c+1)*(app.Uniform_d+1);
                
                app.NoE = app.Uniform_c*app.Uniform_d;
                
                NPE = 4;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   Nodes   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.NL = zeros(app.NoN, PD);
                
                a = app.Uniform_a/app.Uniform_c;
                b = app.Uniform_b/app.Uniform_d;
                
                n=1;
                
                for i = 1:app.Uniform_d+1
                    
                   for j = 1:app.Uniform_c+1
                       app.NL(n,1) = q(1,1) + (j-1)*a;
                       app.NL(n,2) = q(1,2) + (i-1)*b;
                       n = n+1;
                       
                   end
                   
                end
                       
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%   Elements   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       
%                 app.EL = zeros(NoE, NPE);
%                 
%                 for i = 1:m
%                     
%                     for j = 1:p
%                         
%                         if j == 1
%                             
%                             EL((i-1)*p+j,1) = (i-1)*(p+1)+j;
%                             EL((i-1)*p+j,2) = EL((i-1)*p+j,1) + 1;
%                             EL((i-1)*p+j,4) = EL((i-1)*p+j,1) + (p+1);
%                             EL((i-1)*p+j,3) = EL((i-1)*p+j,4) + 1;
%                             
%                         else
%                             
%                             EL((i-1)*p+j,1) = EL((i-1)*p+j-1,2);
%                             EL((i-1)*p+j,4) = EL((i-1)*p+j-1,3);
%                             EL((i-1)*p+j,2) = EL((i-1)*p+j,1) + 1;
%                             EL((i-1)*p+j,3) = EL((i-1)*p+j,4) + 1;
%                             
%                         end
%                         
%                     end
%                     
%                 end
%                 
            %     if isequal(element_type, 'D2TR3N')
            %         
            %         NPE_new = 3;
            %         EL_new = zeros(2*NoE, NPE_new);
            %         
            %         for i = 1:NoE
            %             
            %             EL_new(2*(i-1)+1,1) = EL(i,1);
            %             EL_new(2*(i-1)+1,2) = EL(i,2);
            %             EL_new(2*(i-1)+1,3) = EL(i,3);
            %             
            %             EL_new(2*(i-1)+2,1) = EL(i,1);
            %             EL_new(2*(i-1)+2,2) = EL(i,3);
            %             EL_new(2*(i-1)+2,3) = EL(i,4);
            %             
            %         end
            %         
            %         EL = EL_new;
            %         
            %     end
            
            
                for i = 1:app.NoN
                    hold(app.UIAxes_9,'on');
                    plot(app.UIAxes_9, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                    text(app.UIAxes_9, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
                end
                
                axis(app.UIAxes_9,"equal")
            
                Node_Multiplier = app.Uniform_c+1;
                app.EL = [];
            
                for i = 0:(app.Uniform_c-1)
                    for j = 1:app.Uniform_c
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [(Node_Multiplier*i)+j (Node_Multiplier*i)+j+1];
            
                    end
                end
            
                for i = 1:(app.Uniform_c+1)
                    for j = 0:(app.Uniform_c-2)
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c+1];
            
                    end
                end
            
                for i = 1:app.Uniform_c
                    for j = 0:(app.Uniform_c-2)
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c+2];
            
                    end
                end
            
                for i = 2:app.Uniform_c+1
                    for j = 0:(app.Uniform_c-2)
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c];
            
                    end
                end
            
                app.EL = unique(app.EL,"rows");


                for i=1 : size(app.EL,1)
                    H = plot(app.UIAxes_9,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H.Color(4) = 0.2;
                
                end

                app.ofNodesEditField_2.Value = size(app.NL,1);
                app.ofElementsEditField_2.Value = size(app.EL,1);
         
            end

            function Void_Circle_Mesher(app)

                app.NodeSize = 11;
                
                d1 = app.Circle_aEditField.Value;
                d2 = app.Circle_bEditField.Value;
                p = app.Circle_cEditField.Value;
                app.Global_p = p;
                m = app.Circle_dEditField.Value;
                R = app.Circle_rEditField.Value;
            
            
                q = [0 0; d1 0; 0 d2; d1 d2];
                
                PD = 2;
                
                app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                
                app.NoE = 4*p*m;
                
                NPE = 4;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   Nodes   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.NL = zeros(app.NoN, PD);
                
                a = d1/p;
                b = d2/p;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 1  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                coor11 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor11(i,1) = q(1,1) + (i-1)*a;
                    coor11(i,2) = q(1,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor11(m*(p+1)+i,1) = R*cos((5*pi/4) + (i-1)*(pi/2)/p) + (d1/2);
                    coor11(m*(p+1)+i,2) = R*sin((5*pi/4) + (i-1)*(pi/2)/p) + (d2/2);
                    
                end
                
                %%%% DEBUGGING PLOT %%%%
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 
                % axis equal
                
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                        dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                        
                        coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                        coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 2  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                coor22 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor22(i,1) = q(3,1) + (i-1)*a;
                    coor22(i,2) = q(3,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor22(m*(p+1)+i,1) = R*cos((3*pi/4) - (i-1)*(pi/2)/p) + (d1/2);
                    coor22(m*(p+1)+i,2) = R*sin((3*pi/4) - (i-1)*(pi/2)/p) + (d2/2);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                        dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                        
                        coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                        coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                        
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 3  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                coor33 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor33(i,1) = q(1,1);
                    coor33(i,2) = q(1,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor33(m*(p-1)+i,1) = R*cos((5*pi/4) - (i)*(pi/2)/p) + (d1/2);
                    coor33(m*(p-1)+i,2) = R*sin((5*pi/4) - (i)*(pi/2)/p) + (d2/2);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                        dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                        
                        coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                        coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                    
                end
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 4  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                coor44 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor44(i,1) = q(2,1);
                    coor44(i,2) = q(2,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor44(m*(p-1)+i,1) = R*cos((7*pi/4) + (i)*(pi/2)/p) + (d1/2);
                    coor44(m*(p-1)+i,2) = R*sin((7*pi/4) + (i)*(pi/2)/p) + (d2/2);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                        dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                        
                        coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                        coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                    
                end
                
                
                for i = 1:m+1
                    
                    app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                                 coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                                 flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                                 flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
                    
                end
                
                for i = 1:app.NoN
                    hold(app.UIAxes_9,'on');
                    plot(app.UIAxes_9, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                    text(app.UIAxes_9, app.NL(i,1),app.NL(i,2), num2str(i), 'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
                end
                
                axis(app.UIAxes_9,"equal")
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%   Elements   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
%                 EL = zeros(NoE, NPE);
%                 
%                 for i = 1:m
%                     
%                     for j = 1:4*p
%                         
%                         if j == 1
%                             
%                             EL((i-1)*(4*p)+j,1) = (i-1)*(4*p) + 1;
%                             EL((i-1)*(4*p)+j,2) = EL((i-1)*(4*p)+j,1) + 1;
%                             EL((i-1)*(4*p)+j,4) = EL((i-1)*(4*p)+j,1) + 4*p;
%                             EL((i-1)*(4*p)+j,3) = EL((i-1)*(4*p)+j,4) + 1;
%                             
%                         elseif j == 4*p
%                             
%                             EL((i-1)*(4*p)+j,1) = (i)*(4*p);
%                             EL((i-1)*(4*p)+j,2) = (i-1)*(4*p) + 1;
%                             EL((i-1)*(4*p)+j,3) = EL((i-1)*(4*p)+j,1) + 1;
%                             EL((i-1)*(4*p)+j,4) = EL((i-1)*(4*p)+j,1) + 4*p;
%                             
%                         else
%                             
%                             EL((i-1)*(4*p)+j,1) = EL((i-1)*(4*p)+j-1,2);
%                             EL((i-1)*(4*p)+j,4) = EL((i-1)*(4*p)+j-1,3);
%                             EL((i-1)*(4*p)+j,3) = EL((i-1)*(4*p)+j,4) + 1;
%                             EL((i-1)*(4*p)+j,2) = EL((i-1)*(4*p)+j,1) + 1;
%                             
%                         end
%                         
%                     end
%                        
%                 end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            %     if isequal(element_type,'D2TR3N')
            %         
            %         NPE_new = 3;
            %         EL_new = zeros(2*NoE, NPE_new);
            %         
            %         NPE_new = 3;
            %         EL_new = zeros(2*NoE, NPE_new);
            %         
            %         for i = 1:NoE
            %             
            %             EL_new(2*(i-1)+1,1) = EL(i,1);
            %             EL_new(2*(i-1)+1,2) = EL(i,2);
            %             EL_new(2*(i-1)+1,3) = EL(i,3);
            %             
            %             EL_new(2*(i-1)+2,1) = EL(i,1);
            %             EL_new(2*(i-1)+2,2) = EL(i,3);
            %             EL_new(2*(i-1)+2,3) = EL(i,4);
            %             
            %         end
            %         
            %         EL = EL_new;
            %         
            %     end
            
                
                app.NL
                app.EL = [];
                Node_Multiplier = p * 4;
                CriticalNodes = [];
            
                for j = 1:(m+1)
                    CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
                    CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
                end
            
                CriticalNodes = transpose(CriticalNodes);
                
                 for i = 1:size(app.NL,1)
                    for j = 1:(m+1)
                        if i == j*Node_Multiplier
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                        end
                    end
                end
            
                for i = 1:size(app.NL,1)-1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i i+1];
                end
            
                for k = 1:size(app.NL,1) - Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
                end
            
                for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
                    if ismember(a,CriticalNodes)
                    else
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
                    end
                end
            
                for c = 1:(size(app.NL,1)-Node_Multiplier)
                    for d = 0:(m-1)
                        if c == (d*Node_Multiplier)+1
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                        else
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                        end
                    end
                end
                app.EL = unique(app.EL,"rows");
            
                for i=1 : size(app.EL,1)
                    H = plot(app.UIAxes_9, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H.Color(4) = 0.2;
                
                end

                app.ofNodesEditField_2.Value = size(app.NL,1);
                app.ofElementsEditField_2.Value = size(app.EL,1);
            end


            function Void_Square_Mesher(app)

                app.NodeSize = 11;

                d1 = app.Square_aEditField.Value;
                d2 = app.Square_bEditField.Value;
                p = app.Square_cEditField.Value;
                m = app.Square_dEditField.Value;
                c = app.Square_eEditField.Value;

                app.Global_p = p;


                q = [0 0; d1 0; 0 d2; d1 d2];
                
                PD = 2;
                
                app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                
                app.NoE = 4*p*m;
                
                NPE = 4;
                
                app.NL = zeros(app.NoN, PD);
                
                a = d1/p;
                b = d2/p;
                %region 1
                
                coor11 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor11(i,1) = q(1,1) + (i-1)*a;
                    coor11(i,2) = q(1,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor11(m*(p+1)+i,1) = ((d1-c)/2)+(i-1)*(c/p);
                    coor11(m*(p+1)+i,2) = ((d2-c)/2);
                    
                end
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                        dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                        
                        coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                        coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                
                axis(app.UIAxes_9,"equal")
                
                
                %region 2
                
                coor22 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor22(i,1) = q(3,1) + (i-1)*a;
                    coor22(i,2) = q(3,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor22(m*(p+1)+i,1) = ((d1-c)/2)+(i-1)*(c/p);
                    coor22(m*(p+1)+i,2) = ((d2+c)/2);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                        dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                        
                        coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                        coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                
                axis(app.UIAxes_9,"equal")
                %Region 3
                
                coor33 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor33(i,1) = q(1,1);
                    coor33(i,2) = q(1,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor33(m*(p-1)+i,1) = (d1-c)/2;
                    coor33(m*(p-1)+i,2) = ((d2-c)/2)+(i)*(c/p);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                        dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                        
                        coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                        coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                
                    
                end
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                
                axis(app.UIAxes_9,"equal")
                
                
                %region4


                coor44 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor44(i,1) = q(2,1);
                    coor44(i,2) = q(2,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor44(m*(p-1)+i,1) = (d1+c)/2;
                    coor44(m*(p-1)+i,2) = ((d2-c)/2)+(i)*(c/p);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                        dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                        
                        coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                        coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                    
                end
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor44(i,1),coor44(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor44(i,1),coor44(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 

                axis(app.UIAxes_9,"equal")

                for i = 1:m+1
                    
                    app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                                 coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                                 flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                                 flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
                    
                end
                for i = 1:app.NoN
                    hold(app.UIAxes_9,'on');
                    plot(app.UIAxes_9, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                    text(app.UIAxes_9, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
                end
                
                axis(app.UIAxes_9,"equal")
                
                app.NL
                app.EL = [];
                Node_Multiplier = p * 4;
                CriticalNodes = [];
            
                for j = 1:(m+1)
                    CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
                    CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
                end
            
                CriticalNodes = transpose(CriticalNodes);
                
                 for i = 1:size(app.NL,1)
                    for j = 1:(m+1)
                        if i == j*Node_Multiplier
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                        end
                    end
                end
            
                for i = 1:size(app.NL,1)-1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i i+1];
                end
            
                for k = 1:size(app.NL,1) - Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
                end
            
                for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
                    if ismember(a,CriticalNodes)
                    else
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
                    end
                end
            
                for c = 1:(size(app.NL,1)-Node_Multiplier)
                    for d = 0:(m-1)
                        if c == (d*Node_Multiplier)+1
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                        else
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                        end
                    end
                end
                app.EL = unique(app.EL,"rows");
            
                for i=1 : size(app.EL,1)
                    H = plot(app.UIAxes_9, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H.Color(4) = 0.2;
                
                end

                app.ofNodesEditField_2.Value = size(app.NL,1);
                app.ofElementsEditField_2.Value = size(app.EL,1);
            
            end


            function Void_Rhombus_Mesher(app)

                app.NodeSize = 11;

                d1 = app.Rhombus_aEditField.Value;
                d2 = app.Rhombus_bEditField.Value;
                p = app.Rhombus_cEditField.Value;
                m = app.Rhombus_dEditField.Value;
                c = app.Rhombus_eEditField.Value;

                app.Global_p = p;
                
            
                q = [0 0; d1 0; 0 d2; d1 d2];
                
                PD = 2;
                
                app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                
                beta= pi/3;
                alfa=pi/6;
                
                
                app.NoE = 4*p*m;
                
                NPE = 4;
                
                app.NL = zeros(app.NoN, PD);
                
                a = d1/p;
                b = d2/p;
                %region 1
                
                coor11 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor11(i,1) = q(1,1) + (i-1)*a;
                    coor11(i,2) = q(1,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor11(m*(p+1)+i,1) = ((d1-c)/2)+(i-1)*(c/p);
                    coor11(m*(p+1)+i,2) = ((d2-c)/2);
                    
                end
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                        dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                        
                        coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                        coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 
                % axis equal
                
                
                %region 2
                
                coor22 = zeros((p+1)*(m+1), PD);
                
                for i = 1:p+1
                    
                    coor22(i,1) = q(3,1) + (i-1)*a;
                    coor22(i,2) = q(3,2);
                    
                end
                
                for i = 1:p+1
                    
                    coor22(m*(p+1)+i,1) = ((d1-c)/2)+c*sin(beta-alfa)+(i-1)*(c/p);
                    coor22(m*(p+1)+i,2) = ((d2+c*cos(beta-alfa))/2);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p+1
                        
                        dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                        dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                        
                        coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                        coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                        
                    end
                    
                end
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 
                % axis equal
                
                
                %Region 3
                
                coor33 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor33(i,1) = q(1,1);
                    coor33(i,2) = q(1,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor33(m*(p-1)+i,1) = (c*cos(alfa))/(3*p) + ((d1-c)/2)+(i-1)*(c*cos(2*alfa)/p);
                    coor33(m*(p-1)+i,2) = ((d2-c)/2)+(i)*(c*1.2*sin(2*alfa)/p);
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                        dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                        
                        coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                        coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                
                    
                end
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 
                % axis equal
                %region4


                coor44 = zeros((p-1)*(m+1), PD);
                
                for i = 1:p-1
                    
                    coor44(i,1) = q(2,1);
                    coor44(i,2) = q(2,2) + (i)*b;
                    
                end
                
                for i = 1:p-1
                    
                    coor44(m*(p-1)+i,1) = c/(1.5*p) + ((d1+c)/2)+(i-1)*(c*cos(2*alfa)/(p));
                    coor44(m*(p-1)+i,2) = (c*sin(alfa))/(2*p) + ((d2-c)/2)+(i)*(c*sin(2*alfa)/(p));
                    
                end
                
                for i = 1:m-1
                    
                    for j = 1:p-1
                        
                        dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                        dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                        
                        coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                        coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                        
                    end
                    
                end
                
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p+1)*(m+1)
                %     hold on;
                %     plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % axis equal
                % for i = 1:(p-1)*(m+1)
                %     hold on;
                %     plot(coor44(i,1),coor44(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                %     text(coor44(i,1),coor44(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
                % end
                % 
                % axis equal
                
                for i = 1:m+1
                    
                    app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                                 coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                                 flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                                 flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
                    
                end
                for i = 1:app.NoN
                    hold(app.UIAxes_9,'on');
                    plot(app.UIAxes_9, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                    text(app.UIAxes_9, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
                end
            
                axis(app.UIAxes_9,"equal")
                
                app.NL
                app.EL = [];
                Node_Multiplier = p * 4;
                CriticalNodes = [];
            
                for j = 1:(m+1)
                    CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
                    CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
                end
            
                CriticalNodes = transpose(CriticalNodes);
                
                 for i = 1:size(app.NL,1)
                    for j = 1:(m+1)
                        if i == j*Node_Multiplier
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                        end
                    end
                end
            
                for i = 1:size(app.NL,1)-1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i i+1];
                end
            
                for k = 1:size(app.NL,1) - Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
                end
            
                for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
                    if ismember(a,CriticalNodes)
                    else
                        app.EL(size(app.EL,1)+1,:) = [0 0];
                        app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
                    end
                end
            
                for c = 1:(size(app.NL,1)-Node_Multiplier)
                    for d = 0:(m-1)
                        if c == (d*Node_Multiplier)+1
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                        else
                            app.EL(size(app.EL,1)+1,:) = [0 0];
                            app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                        end
                    end
                end
                app.EL = unique(app.EL,"rows");
            
                for i=1 : size(app.EL,1)
                    H = plot(app.UIAxes_9, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                    H.Color(4) = 0.2;
                
                end

                app.ofNodesEditField_2.Value = size(app.NL,1);
                app.ofElementsEditField_2.Value = size(app.EL,1);
            
            end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes_4,'on');
            hold(app.UIAxes_5,'on');
            hold(app.UIAxes_6,'on');
            hold(app.UIAxes_7,'on');
            hold(app.UIAxes_8,'on');

            
            X1 = [0 -10 -10 0];
            Y1 = [0 0 -10 -10];
            patch(app.UIAxes_4,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_5,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_6,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_7,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_8,X1,Y1, [.35 .15 .02],"FaceAlpha",.5)


            X2 = [20 10 10 20];
            Y2 = [0 0 -10 -10];
            patch(app.UIAxes_4,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_5,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_6,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_7,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)
            patch(app.UIAxes_8,X2,Y2, [.35 .15 .02],"FaceAlpha",.5)


            X3 = [0 10 10 0];
            Y3 = [-5 -5 -10 -10];
            patch(app.UIAxes_4,X3,Y3, 'b',"FaceAlpha",.5)
            patch(app.UIAxes_5,X3,Y3, 'b',"FaceAlpha",.5)
            patch(app.UIAxes_6,X3,Y3, 'b',"FaceAlpha",.5)
            patch(app.UIAxes_7,X3,Y3, 'b',"FaceAlpha",.5)
            patch(app.UIAxes_8,X3,Y3, 'b',"FaceAlpha",.5)

            X4 = [0 10 10 0];
            Y4 = [-5 -5 -10 -10];
            patch(app.UIAxes_4,X4,Y4, "c" ,"FaceAlpha",.5)
            patch(app.UIAxes_5,X4,Y4, "c" ,"FaceAlpha",.5)
            patch(app.UIAxes_6,X4,Y4, "c" ,"FaceAlpha",.5)
            patch(app.UIAxes_7,X4,Y4, "c" ,"FaceAlpha",.5)
            patch(app.UIAxes_8,X4,Y4, "c" ,"FaceAlpha",.5)

            axis(app.UIAxes_4,[-5 15 -10 7.5]);
            axis(app.UIAxes_5,[-5 15 -10 7.5]);

            axis(app.UIAxes_6,[-5 15 -10 7.5]);
            axis(app.UIAxes_7,[-5 15 -10 7.5]);
            axis(app.UIAxes_8,[-5 15 -10 7.5]);
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            app.nl = [app.X_coor.Value,app.Y_coor.Value];
            
            app.NL = [app.NL;app.nl];
            
            app.NL_Table.Data = app.NL;

            app.NoN = size(app.NL,1);

            app.ofNodesEditField.Value = app.NoN;

            updateAxes(app);


        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            el = [app.StartingNode.Value,app.EndingNode.Value];
            
            if app.NoN < 2

                uialert(app.FEMProject1AppUIFigure,"Heyy! You need at least two nodes to create an element","Input Error")
            else


                app.EL = [app.EL;el];
            
                app.EL_Table.Data = app.EL;

                app.NoE = size(app.EL,1);
                
                app.ofElementsEditField.Value = app.NoE;
 
                updateAxes(app);
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if strcmp(app.Knob.Value,'Tiny')
                app.NodeSize = 11;
            elseif strcmp(app.Knob.Value,'Small')
                app.NodeSize = 17;
            elseif strcmp(app.Knob.Value,'Normal')
                app.NodeSize = 25;
            elseif strcmp(app.Knob.Value,'Big')
                app.NodeSize = 40;
            else 
                app.NodeSize = 50;
            end
    

            updateAxes(app);
            PlotBCsAgainButtonPushed(app);
        end

        % Value changed function: NodeNumberSwitch
        function NodeNumberSwitchValueChanged(app, event)
            app.NodeSwitch = app.NodeNumberSwitch.Value;
            updateAxes(app);
            PlotBCsAgainButtonPushed(app);
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            SelectedButton = app.ButtonGroup.SelectedObject;
            
            switch SelectedButton
                case app.Jet
                    app.colorMap = turbo;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Bone
                    app.colorMap = bone;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Parula
                    app.colorMap = parula;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Autumn
                    app.colorMap = autumn;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
            end        

        end

        % Button pushed function: ReadNLELButton
        function ReadNLELButtonPushed(app, event)
            [inputfile,~] = uigetfile({"*.xlsx";"*.slx"},"Please Select a File");

     
            if ~isequal(inputfile,0)
                app.NL = [];
                app.EL = [];

                app.nl = readmatrix(inputfile,"Range","A:B");
                el = readmatrix(inputfile,"Range","C:D");
                DirchBC = readmatrix(inputfile,"Range","E:F");
                NeuBC = readmatrix(inputfile,"Range","K:L");


                for i = 1:size(app.nl,1)
                    if ~isnan(app.nl(i,:))
                        app.NL = [app.NL; app.nl(i,:)];
                    end
                end

                for i = 1:size(el,1)
                    if ~isnan(el(i,:))
                        app.EL = [app.EL; el(i,:)];
                    end
                end

                app.NL_Table.Data = app.NL;
                app.EL_Table.Data = app.EL;

                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);

                for i = 1:size(DirchBC,1)
                    if ~isnan(DirchBC(i,:))
                        app.ENL(:,3:4) = DirchBC;
                    end
                end
                
                for i = 1:size(NeuBC,1)
                    if ~isnan(NeuBC(i,:))
                        app.ENL(:,11:12) = NeuBC;
                    end
                end
                

                updateAxes(app)                
                PlotBCsAgainButtonPushed(app)
            end


        end

        % Value changed function: ElementNumberSwitch
        function ElementNumberSwitchValueChanged(app, event)
             app.ElementSwitch = app.ElementNumberSwitch.Value;
             updateAxes(app);
             PlotBCsAgainButtonPushed(app);
             
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            format long


            assign_BCs2(app); %write [enl dofsd docs]
            
            ENL3 = app.ENL(:,3);
            ENL4 = app.ENL(:,4);
            ENL3(ENL3==0)=1;
            ENL4(ENL4==0)=1;
            app.ENL(:,3) = ENL3;
            app.ENL(:,4) = ENL4;

            K = assemble_stiffness2(app);


            assemble_forces2(app);
            assemble_displacements2(app);
            
            Kpu = K(1:app.DOFs, 1:app.DOFs);
            Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

         
            F = app.Fp - Kpp*app.Up;
            
            app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
            
            app.Fu = Kuu*app.Uu + Kup*app.Up;
            
            update_nodes2(app);

            app.ENL_Table.Data = app.ENL;


            post_process2(app)


        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            app.BCdirectionSwitch = app.DirectionSwitch.Value;
            
            
        end

        % Value changed function: ForceDispSwitch
        function ForceDispSwitchValueChanged(app, event)
            app.BCforcedisplacementSwitch = app.ForceDispSwitch.Value;
            
            
        end

        % Value changed function: MagnitudeEditField
        function MagnitudeEditFieldValueChanged(app, event)
            app.BCvalue = app.MagnitudeEditField.Value;
            
        end

        % Value changed function: NodeNumberEditField
        function NodeNumberEditFieldValueChanged(app, event)
            app.NodeNumber = app.NodeNumberEditField.Value;
            
            
        end

        % Button pushed function: ApplyBCButton
        function ApplyBCButtonPushed(app, event)
            assign_BCs2(app)
        end

        % Callback function
        function ofelementsEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ClearStructureButton
        function ClearStructureButtonPushed(app, event)
            app.NL = [];
            app.EL = [];
            
            app.NoN = 0;
            app.NoE = 0;
            

            cla(app.UIAxes) 
            cla(app.UIAxes_2) 
            cla(app.UIAxes_3) 
                
            app.DOFs = 0; 
            app.DOCs = 0; 
           
            app.Up = [];
            app.Fp = [];
            app.Fu = [];
            app.Uu = [];
            app.nl = [];

            
            app.ENL = [];
            
            app.NL_Table.Data = [];
            app.EL_Table.Data = [];
            app.ENL_Table.Data = [];


            updateAxes(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            
            
            switch selectedButton
                 case app.Jet_2
                    app.colorMap = turbo;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Bone_2
                    app.colorMap = bone;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Parula_2
                    app.colorMap = parula;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Autumn_2
                    app.colorMap = autumn;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
            end        
        end

        % Value changed function: ElasticModulusPaEditField
        function ElasticModulusPaEditFieldValueChanged(app, event)
            app.E = app.ElasticModulusPaEditField.Value;
            
        end

        % Value changed function: AreamEditField
        function AreamEditFieldValueChanged(app, event)
            app.A = app.AreamEditField.Value;
            
        end

        % Value changed function: ExaggerateSlider
        function ExaggerateSliderValueChanged(app, event)
            app.mag = app.ExaggerateSlider.Value/10;
            post_process2(app)
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            SelectedButton = app.ButtonGroup_3.SelectedObject;
            
            
            switch SelectedButton
                case app.Jet_3
                    app.colorMap = turbo;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Bone_3
                    app.colorMap = bone;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Parula_3
                    app.colorMap = parula;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
                case app.Autumn_3
                    app.colorMap = autumn;
                    colormap(app.UIAxes,app.colorMap);
                    colormap(app.UIAxes_2,app.colorMap);
                    colormap(app.UIAxes_3,app.colorMap);
            end        

            
        end

        % Value changed function: DisplacementDropDown
        function DisplacementDropDownValueChanged(app, event)
            app.DispDrop = app.DisplacementDropDown.Value;
            post_process2(app)
        end

        % Value changed function: NodeIndicatorSwitch
        function NodeIndicatorSwitchValueChanged(app, event)
            app.NodeIndicator = app.NodeIndicatorSwitch.Value;
            updateAxes(app);
            PlotBCsAgainButtonPushed(app);
            
        end

        % Value changed function: ElementIndicatorSwitch
        function ElementIndicatorSwitchValueChanged(app, event)
            app.ElementIndicator = app.ElementIndicatorSwitch.Value;
            updateAxes(app);
            PlotBCsAgainButtonPushed(app);
        end

        % Value changed function: ElementNumberSwitch_2
        function ElementNumberSwitch_2ValueChanged(app, event)
            app.ElementSwitch_2 = app.ElementNumberSwitch_2.Value;
            post_process2(app);
            
        end

        % Value changed function: NodeNumberSwitch_2
        function NodeNumberSwitch_2ValueChanged(app, event)
            app.NodeSwitch_2 = app.NodeNumberSwitch_2.Value;
            post_process2(app);
            
        end

        % Value changed function: NodeIndicatorSwitch_2
        function NodeIndicatorSwitch_2ValueChanged(app, event)
            app.NodeIndicator_2 = app.NodeIndicatorSwitch_2.Value;
            post_process2(app);
        end

        % Value changed function: OldNodeIndicatorSwitch
        function OldNodeIndicatorSwitchValueChanged(app, event)
            app.NodeIndicatorNO = app.OldNodeIndicatorSwitch.Value;
            post_process2(app);
            
        end

        % Callback function
        function PlotBCsAgainButtonPushed(app, event)
            for j = 1:size(app.ENL,1)
                for i = app.ENL(j,3)
                    if i == -1
                        hold(app.UIAxes,'on');
                        xp = [app.NL(j,1) (app.NL(j,1)-0.25) (app.NL(j,1)-0.25)];
                        yp = [app.NL(j,2) (app.NL(j,2)+0.125) (app.NL(j,2)-0.125)];
                        patch(app.UIAxes,xp, yp, 'w', "FaceAlpha",.5)
                    end
                end

                for i = app.ENL(j,4)
                    if i == -1
                        hold(app.UIAxes,'on');
                        xp2 = [app.NL(j,1) (app.NL(j,1)-0.125) (app.NL(j,1)+0.125)];
                        yp2 = [app.NL(j,2) (app.NL(j,2)-0.25) (app.NL(j,2)-0.25)];
                        patch(app.UIAxes,xp2, yp2, 'w',"FaceAlpha",.5)
                    end

                end
                
                for i = app.ENL(j,11)
                    if i > 0
                        plot(app.UIAxes,linspace(app.NL(j,1)+0.1,(app.NL(j,1)+0.5),5),app.NL(j,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                        text(app.UIAxes,app.NL(j,1)+0.6,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                    elseif i < 0
                        plot(app.UIAxes,linspace(app.NL(j,1)-0.1,(app.NL(j,1)-0.5),5),app.NL(j,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                        text(app.UIAxes,app.NL(j,1)-0.65,app.NL(j,2),num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                    end
                end
    
                for i = app.ENL(j,12)
                    if i > 0
                        plot(app.UIAxes,app.NL(j,1),linspace(app.NL(j,2)+0.1,app.NL(j,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                        text(app.UIAxes,app.NL(j,1)-0.03,app.NL(j,2)+0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                    elseif i < 0
                        plot(app.UIAxes,app.NL(j,1),linspace(app.NL(j,2)-0.1,app.NL(j,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                        text(app.UIAxes,app.NL(j,1)-0.03,app.NL(j,2)-0.6,num2str(i) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                    end
                end
                
            end
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            [inputfile,~] = uigetfile({"*.xlsx";"*.slx"},"Please Select a File");

     
            if ~isequal(inputfile,0)
                app.NL = [];
                app.EL = [];

                app.nl = readmatrix(inputfile,"Range","A:B");
                el = readmatrix(inputfile,"Range","C:D");
                DirchBC = readmatrix(inputfile,"Range","E:F");
                NeuBC = readmatrix(inputfile,"Range","K:L");


                for i = 1:size(app.nl,1)
                    if ~isnan(app.nl(i,:))
                        app.NL = [app.NL; app.nl(i,:)];
                    end
                end

                for i = 1:size(el,1)
                    if ~isnan(el(i,:))
                        app.EL = [app.EL; el(i,:)];
                    end
                end

                app.NL_Table.Data = app.NL;
                app.EL_Table.Data = app.EL;

                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);

                for i = 1:size(DirchBC,1)
                    if ~isnan(DirchBC(i,:))
                        app.ENL(:,3:4) = DirchBC;
                    end
                end
                
                for i = 1:size(NeuBC,1)
                    if ~isnan(NeuBC(i,:))
                        app.ENL(:,11:12) = NeuBC;
                        
                    end

                    app.ENLfirst1112 = app.ENL(:,11:12) ;

                    app.ENLfirst1112 = app.ENLfirst1112(1:size(app.NL,1),:);
                end

              
                OPTupdateAxes(app)

                app.ENL1112afteroptimizationused = app.ENLfirst1112;

                app.NoN = size(app.NL,1);
                
                if size(app.ENL,2) ~= 12
    
                    app.ENL = zeros(app.NoN, 12);
                end
    
                app.ENL = app.ENL(1:size(app.NL,1),:);
    
                app.ENL(:,1:2) = app.NL;

                plotForceOPT(app)

                app.ENL

                forceconverter(app)

                app.ENL

                switch app.FixHorizontal
                    case "On"
                        for i = 1:app.NoN
                            if app.ENL(i,2) == 0
                                app.ENL(i,4) = -1;
                            end
                        end
                end


                app.NeuBCLaterUsed = app.ENL(:,11:12);
                
                findweight(app)

                app.TotalWeightEditField.Value = app.TotalW * (-1);

                app.ENL(:,11:12) = zeros(app.NoN,2);

                app.ENL(:,11:12) = app.NeuBCLaterUsed;


                app.UseOpt2 = app.ENL;

%                 app.UseOpt2(:,11:12) = app.ENL(:,11:12);

                app.ENL


            end

        end

        % Value changed function: NodeNumberSwitch_3
        function NodeNumberSwitch_3ValueChanged(app, event)
            app.OPTNodeSwitch = app.NodeNumberSwitch_3.Value;
            OPTupdateAxes(app)
            plotForceOPT(app)
        end

        % Value changed function: ElementNumberSwitch_3
        function ElementNumberSwitch_3ValueChanged(app, event)
            app.OPTElementSwitch = app.ElementNumberSwitch_3.Value;
            OPTupdateAxes(app)
            plotForceOPT(app)
        end

        % Value changed function: ElementIndicatorSwitch_2
        function ElementIndicatorSwitch_2ValueChanged(app, event)
            app.OPTElementIndicator = app.ElementIndicatorSwitch_2.Value;
            OPTupdateAxes(app)
            plotForceOPT(app)
        end

        % Value changed function: NodeIndicatorSwitch_3
        function NodeIndicatorSwitch_3ValueChanged(app, event)
            app.OPTNodeIndicator = app.NodeIndicatorSwitch_3.Value;
            OPTupdateAxes(app)
            plotForceOPT(app)
        end

        % Callback function
        function OptimizeButtonPushed(app, event)
             format long


            assign_BCs_OPTIMIZATION(app); %write [enl dofsd docs]
            
            ENL3 = app.ENL(:,3);
            ENL4 = app.ENL(:,4);
            ENL3(ENL3==0)=1;
            ENL4(ENL4==0)=1;
            app.ENL(:,3) = ENL3;
            app.ENL(:,4) = ENL4;

%             findweight(app);

            K = assemble_stiffness2(app);


            assemble_forces2(app);
            assemble_displacements2(app);
            
            Kpu = K(1:app.DOFs, 1:app.DOFs);
            Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

         
            F = app.Fp - Kpp*app.Up;
            
            app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
            
            app.Fu = Kuu*app.Uu + Kup*app.Up;
            
            update_nodes2(app);

            app.ENL_Table.Data = app.ENL;

           
            post_process_FirstOptimization(app)
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)
            app.ro = app.DensityEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ofElementsEditField
        function ofElementsEditFieldValueChanged(app, event)
            value = app.ofElementsEditField.Value;
            
        end

        % Value changed function: ofNodesEditField
        function ofNodesEditFieldValueChanged(app, event)
            value = app.ofNodesEditField.Value;
            
        end

        % Value changed function: AreamEditField_2
        function AreamEditField_2ValueChanged(app, event)
            app.A = app.AreamEditField_2.Value;
            
        end

        % Value changed function: ElasticModulusPaEditField_2
        function ElasticModulusPaEditField_2ValueChanged(app, event)
            app.E = app.ElasticModulusPaEditField_2.Value;
            
        end

        % Value changed function: TotalWeightEditField
        function TotalWeightEditFieldValueChanged(app, event)
            value = app.TotalWeightEditField.Value;
            
        end

        % Callback function
        function OptimizeButtonPushed2(app, event)
            format long

            app.ENL
            app.NoN
            app.NoE
    
            assign_BCs_OPTIMIZATION(app); %write [enl dofsd docs]
            
            ENL3 = app.ENL(:,3);
            ENL4 = app.ENL(:,4);
            ENL3(ENL3==0)=1;
            ENL4(ENL4==0)=1;
            app.ENL(:,3) = ENL3;
            app.ENL(:,4) = ENL4;

            findweight_Modified(app);

            K = assemble_stiffness2(app);


            assemble_forces2(app);
            assemble_displacements2(app);


            app.ENL
            app.NoN
            app.NoE
            app.DOFs
            app.DOCs
    
            
            Kpu = K(1:app.DOFs, 1:app.DOFs);
            Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

         
            F = app.Fp - Kpp*app.Up;
            
            app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
            
            app.Fu = Kuu*app.Uu + Kup*app.Up;
            
            update_nodes2(app);

            app.ENL_Table.Data = app.ENL;

           
            post_process_FirstOptimization(app)

           
        end

        % Button pushed function: 
        % MergeCloseNodesShowStressDistributionButton
        function MergeCloseNodesShowStressDistributionButtonPushed(app, event)
%             app.NL = app.ENL(:,1:2);
            
%             for i = 1:size(app.EL,1)
% 
% %                 app.ENL(:,9:10) = zeros(size(app.ENL,1),2);
% 
%                 Node1 = app.EL(i,1);
%                 Node2 = app.EL(i,2);
% 
%                 ElLength = sqrt((app.ENL(Node1,1)-app.ENL(Node2,1))^2+(app.ENL(Node1,2)-app.ENL(Node2,2))^2);
%                 
% 
%                 if ElLength > 3
%                     msgbox("At least one of the element lengths has reached to 3m","Warning","warn")
% 
%                 end
%             end

%                 if ElLength > 3
%                     app.NL(size(app.NL,1)+1,1) = (app.ENL(Node1,1)+app.ENL(Node2,1))/2;
%                     app.NL(size(app.NL,1),2) = (app.ENL(Node1,2)+app.ENL(Node2,2))/2;
%                     app.EL(i,2) = (size(app.NL,1));
%                     app.EL(size(app.EL)+1,1) = (size(app.NL,1));
%                     app.EL(size(app.EL),2) = Node2;
%                     app.ENL(size(app.NL,1),1:2) = [0 0];
%                     app.ENL(:,1:2) =  app.NL;
%                 end
%             end
%             app.ENL
%             app.NL
%             app.EL
% 
%             for a = 2:size(app.ENL,1)
%                 if app.ENL(a,3) == -1
%                     FixedPoint2 = a;
%                 end
%             end
% 
%             ENL_Temp = app.ENL;
% 
%             app.ENL = zeros(size(app.NL,1),12);
% 
%             FixedPoint2 = FixedPoint2 +1;
% 
%             app.ENL(:,1:2) =  app.NL;
% 
%             app.ENL(1:FixedPoint2,3:4) = ENL_Temp(1:FixedPoint2,3:4);
%             app.ENL(FixedPoint2:size(app.ENL,1),3:4) = ones(size(app.ENL,1)-FixedPoint2+1,2);
%             
% %             app.ENL(1:size(ENL_Temp,1),3:4) = ENL_Temp(:,3:4);
%             app.ENL(:,11:12) = zeros(size(app.NL,1),2);
%             app.ENL(1:size(ENL_Temp,1),11:12) = ENL_Temp(:,11:12);
%             
%             app.ENL
% 
%             PD = 2;
%             app.DOFs = 0;
%             app.DOCs = 0;
% 
%             for i = 1:app.NoN
%                 for j = 1:PD
%                     if (app.ENL(i,(PD+j)) == -1)
% 
%                         app.DOCs = app.DOCs - 1;
%                         app.ENL(i, 2*PD+j) = app.DOCs;
%                     else
%                         app.DOFs = app.DOFs + 1;
%                         app.ENL(i, 2*PD+j) = app.DOFs;
%                     end
%                 end
%             end
% 
% 
%             for i = 1:app.NoN
%                 for j= 1:PD
%                     if (app.ENL(i,(2*PD+j)) < 0 )
% 
%                         app.ENL(i, 3*PD+j) = app.DOFs + abs(app.ENL(i,(2*PD+j)));
%                     else
% 
%                         app.ENL(i, 3*PD+j) = app.ENL(i,2*PD+j);
%                     end
%                 end
%             end
% 
%             app.DOCs = abs(app.DOCs);
% 
% 
%             format long
% 
% 
% 
%             assign_BCs_OPTIMIZATION(app); %write [enl dofsd docs]
% 
%             ENL3 = app.ENL(:,3);
%             ENL4 = app.ENL(:,4);
%             ENL3(ENL3==0)=1;
%             ENL4(ENL4==0)=1;
%             app.ENL(:,3) = ENL3;
%             app.ENL(:,4) = ENL4;
% 
% %             findweight(app);
% 
% 
% 
% %             K = assemble_stiffnessOPT2(app);
% % 
% %             assemble_forcesOPT2(app);
% % 
% %             app.ENL
% % 
% %             assemble_displacementsOPT2(app);
% % 
% %             
% %             Kpu = K(1:app.DOFs, 1:app.DOFs);
% %             Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
% %             Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
% %             Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);
% % 
% %             Kpu
% %             Kpp
% %             Kuu
% %             Kup
% % 
% % 
% %             F = app.Fp2 - Kpp*app.Up2;
% % 
% %             app.Uu2 = Kpu\F;  % Instead of inv(Kpu)*F to be fast...
% % 
% %             app.Fu2 = Kuu*app.Uu2 + Kup*app.Up2;
% % 
% %             app.ENL
% % 
% %             update_nodesOPT2(app);
% 
%             app.ENL_Table.Data = app.ENL;


%             post_process_SecondOptimization(app)


        breaker = 0;
        
        
        for k = 1:size(app.NL,1)
        
            for l = k+1:size(app.NL,1)
        
                Distance = sqrt(((app.NL(k,1) - app.NL(l,1))^2) + ((app.NL(k,2) - app.NL(l,2))^2)) ;
        
                if Distance < 0.1
                    if breaker == 0
                    
                        FirstNode = app.NL(k,:);
                        SecondNode = app.NL(l,:);
                        
                        
                        app.NL(k,:) = [];
                        app.NL(l-1,:) = [];
                        app.NL((size(app.NL,1)+1),:) = [0 0] ;
                        app.NL(size(app.NL,1),:) = [((FirstNode(:,1) + SecondNode(:,1))/2) ((FirstNode(:,2) + SecondNode(:,2))/2)] ;
            
                        
                        app.EL(app.EL==k) = size(app.NL,1)*(-1);
                        app.EL(app.EL==l) = size(app.NL,1)*(-1);
                        breaker = 1;
            
                        for a = 1:numel(app.EL)
                            if a > k                     
                                if a > l
                                    app.EL(app.EL==a) = a-2;
                                else
                                    app.EL(app.EL==a) = a-1;
                                
            
                                end
            
                            end


                        end
                        break
                       
                    end
                end
        
            end
        
        end
        

        for i = 1:size(app.EL,1)
            if app.EL(i,1) < 0
                if app.EL(i,1) == app.EL(i,2)
                    app.EL(i,:) = [];
                    break
                end
            end
        end


        app.EL = abs(app.EL);

        app.ENL_wait = app.ENL;
        app.ENL = zeros(size(app.NL,1),12);
        app.ENL(:,1:2) = app.NL;
        app.ENL(:,3:4) = ones(size(app.NL,1),2);
        
        for z = 1: size(app.NL,1)
            if app.ENL(z,1:2) == [0 0]
                app.ENL(z,3:4) = [-1 -1];
            end        
             if app.ENL(z,1) == max(app.NL(:,1))
                 app.ENL(z,3:4) = [-1 -1];
             end
        end

        app.NL = [0 0];
        for i = 1:size(app.EL,1)
    
            if sum(ismember([app.NL_RecordedX(i,1) app.NL_RecordedY(i,1)],app.NL,"rows")) == 0
                app.NL(size(app.NL,1)+1,1:2) = zeros(1,2);
                app.NL(size(app.NL,1),1) = app.NL_RecordedX(i,1);
                app.NL(size(app.NL,1),2) = app.NL_RecordedY(i,1);
        %         app.EL(i,1) = [(2*i)-1];
            else
        %         app.EL(i,1) = find(ismember([app.NL_RecordedX(i,1) app.NL_RecordedY(i,1)],app.NL,"rows") == 1);
            end


            if sum(ismember([app.NL_RecordedX(i,2) app.NL_RecordedY(i,2)],app.NL,"rows")) == 0
                app.NL(size(app.NL,1)+1,1:2) = zeros(1,2);
                app.NL(size(app.NL,1),1) = app.NL_RecordedX(i,2);
                app.NL(size(app.NL,1),2) = app.NL_RecordedY(i,2);
        %         app.EL(i,2) = [2*i];
            else
        %         app.EL(i,2) = find(ismember([app.NL_RecordedX(i,2) app.NL_RecordedY(i,2)],app.NL,"rows") == 1);
            end
        end


        for f = 1 : size(app.ENL,1)
            if app.ENLfirst1112(f,2) == sum(app.ENLfirst1112(:,2))
                app.ForceX = app.ENL(f,1);
                app.ForceAmount = sum(app.ENLfirst1112(:,2));
            end
        end

%         app.ENL = zeros(size(app.NL,1),12);
        app.ENL(:,1:2) = app.NL;
        for z = 1 : size(app.ENL,1)

            if abs(app.ENL(z,1)-app.ForceX) < 0.2
                if abs(app.ENL(z,2)) < 0.2
                    app.ENL(z,12) = app.ForceAmount;
                end
            end
        end


        post_process_SecondOptimization(app)

%         app.Up = [];
%         app.Fp = [];
%         app.Fu = [];
%         app.Uu = [];
%         
        

        format long


        assign_BCs2(app); %write [enl dofsd docs]
        
        ENL3 = app.ENL(:,3);
        ENL4 = app.ENL(:,4);
        ENL3(ENL3==0)=1;
        ENL4(ENL4==0)=1;
        app.ENL(:,3) = ENL3;
        app.ENL(:,4) = ENL4;

        K = assemble_stiffness2(app);


        assemble_forces3(app);
        assemble_displacements3(app);
        
        Kpu3 = K(1:app.DOFs, 1:app.DOFs);
        Kpp3 = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
        Kuu3 = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
        Kup3 = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

     
        F = app.Fp3 - Kpp3*app.Up3;
        
        app.Uu3 = inv(Kpu3)*F;  % Instead of inv(Kpu)*F to be fast...
        
        app.Fu3 = Kuu3*app.Uu3 + Kup3*app.Up3;
        
        update_nodes3(app);

        app.ENL_Table.Data = app.ENL;
 
        Plotter(app)

        % UPDATES NL AND EL, IN EVERY TIME IT MERGES ONLY TWO NODES, NOT
        % EVERY NODE


        end

        % Value changed function: FixHorizontalSwitch
        function FixHorizontalSwitchValueChanged(app, event)
            app.FixHorizontal = app.FixHorizontalSwitch.Value;
            
        end

        % Button pushed function: OptimizeBridgeButton
        function OptimizeBridgeButtonPushed(app, event)
            
            app.Lamp_5.Color = "y";
            OptimizeButtonPushed2(app)

            IterationNumber = 0 ;
            Stopper = 0;

            for i = 1:app.MaxIterationNumberEditField.Value


                pause(1/500);
                
                app.ENL_A = zeros(size(app.ENL,1),12);

                app.ENL_A(:,1:2) = app.ENLFirst(:,1:2);
                app.ENL_A(:,11:12) = app.ENLFirst(:,11:12);
                app.ENL_A(:,3:4) = ones(size(app.ENLFirst,1),2);
                
                for j = 1:size(app.ENLFirst,1)
                    if app.ENLFirst(j,3:4) == [-1 -1]
                        app.ENL_A(j,3:4) = [-1 -1];
                    end
                end
                
                
                app.NL_A = app.ENL_A(:,1:2);
                app.EL_A = app.EL;

                findweight_A(app)

                app.TotalWeightGauge.Value = sum(abs(app.TotalW_A));

                app.TotalWeightStepbystepEditField.Value = sum(abs(app.TotalW_A));
 
                Analyzer(app);

                IterationNumber = IterationNumber +1 ;

                app.ENL_A

                if IterationNumber == 1

                    x = abs(min(app.ENL_A(:,10)*1000000));
                    y = 100;
                    r = mod(x,y);
                    Modification_Multiplier = (x-r);
                end

                TEST = app.ENL_A;

                app.IterationNumberField.Value = IterationNumber;

                app.IterationsDoneGauge.Value = IterationNumber;

                app.MaximumDeflectionEditField.Value = abs(min(app.ENL_A(:,10)*1000000))-Modification_Multiplier;

                app.MaximumDeflectionGauge.Value = abs(min(app.ENL_A(:,10)*1000000))-Modification_Multiplier;

                ScoreNew = ((abs(min(app.ENL_A(:,10)))*1000000)-Modification_Multiplier)*sum(abs(app.TotalW_A));

                app.ScoreEditField.Value = ScoreNew;


                app.ScoreGauge.Value = ScoreNew;

                switch app.Score_Based
                    case "On"

                        if IterationNumber > 1
        
                            if ScoreNew > ScoreKnown
        
                                break
        
        
                            end
                        end

                    case "Off"

                        if IterationNumber > app.MaxIterationNumberEditField.Value
                            break
                        end
                end

                for a = 1:size(app.EL,1)

%                 app.ENL(:,9:10) = zeros(size(app.ENL,1),2);

                    Node1 = app.EL(a,1);
                    Node2 = app.EL(a,2);
    
                    ElLength = sqrt((app.ENL(Node1,1)-app.ENL(Node2,1))^2+(app.ENL(Node1,2)-app.ENL(Node2,2))^2);
                    
    
                    if ElLength > 3
                        
                        Stopper = 1;
                    end
                end

                if Stopper == 1
                    msgbox("At least one of the element lengths has reached to 3m","Warning","warn")
                    break
                end


                ENLKnown = app.ENL_A;
                
                ScoreKnown = ScoreNew;
                

                app.NoN_A = [];
                app.NL_A = [];
                app.ENL_A = [];
                app.NoE_A = [];
                app.EL_A = [];
                app.nl_A = [];
                app.DOFs_A;
                app.DOCs_A;
                app.Fp_A = [];
                app.Up_A = [];
                app.Uu_A = [];
                app.Fu_A = [];

                OptimizeButtonPushed2(app)


            end
            app.Lamp_5.Color = "g";

            app.ENL(:,1) = TEST(:,1) + (app.mag*TEST(:,9));
            app.ENL(:,2) = TEST(:,2) + (app.mag*(-1)*TEST(:,10));
            app.ENL(:,9:10) = zeros(size(app.ENL,1),2);
            app.ENL(:,11:12) = app.ENL1112afteroptimizationused;

            app.NL = app.ENL(:,1:2);
          
            app.ENL

            app.ENL_Table.Data
            
            
        end

        % Value changed function: IterationNumberField
        function IterationNumberFieldValueChanged(app, event)
            value = app.IterationNumberField.Value;
            
        end

        % Value changed function: MaxIterationNumberEditField
        function MaxIterationNumberEditFieldValueChanged(app, event)
            value = app.MaxIterationNumberEditField.Value;
            app.UIAxes_6
        end

        % Value changed function: MaximumDeflectionEditField
        function MaximumDeflectionEditFieldValueChanged(app, event)
            value = app.MaximumDeflectionEditField.Value;
            
        end

        % Value changed function: TotalWeightStepbystepEditField
        function TotalWeightStepbystepEditFieldValueChanged(app, event)
            value = app.TotalWeightStepbystepEditField.Value;
            
        end

        % Value changed function: ScoreEditField
        function ScoreEditFieldValueChanged(app, event)
            value = app.ScoreEditField.Value;
            
        end

        % Button down function: UIAxes_6
        function UIAxes_6ButtonDown(app, event)
            
        end

        % Button pushed function: BeamPlotButton
        function BeamPlotButtonPushed(app, event)
            UniformMesher(app)
            app.Void = 1;
        end

        % Callback function
        function Knob_2ValueChanged(app, event)

            app.Knob_2value = app.Knob_2.Value;
            if strcmp(app.Knob_2.Value,'Tiny')
                app.NodeSize = 11;
            elseif strcmp(app.Knob_2.Value,'Small')
                app.NodeSize = 17;
            elseif strcmp(app.Knob_2.Value,'Normal')
                app.NodeSize = 25;
            elseif strcmp(app.Knob_2.Value,'Big')
                app.NodeSize = 40;
            else 
                app.NodeSize = 50;
            end
        end

        % Button pushed function: CirclePlotButton
        function CirclePlotButtonPushed(app, event)
            Void_Circle_Mesher(app)
        end

        % Button pushed function: SquarePlotButton
        function SquarePlotButtonPushed(app, event)
            Void_Square_Mesher(app)
        end

        % Button pushed function: ClearStructureButton_2
        function ClearStructureButton_2Pushed(app, event)
            app.NL = [];
            app.EL = [];
            
            app.NoN = 0;
            app.NoE = 0;
            

            cla(app.UIAxes_9) 

            app.Force13 = 500;
            app.Void = 0;
             
                
            app.DOFs = 0; 
            app.DOCs = 0; 
           
            app.Up = [];
            app.Fp = [];
            app.Fu = [];
            app.Uu = [];
            app.nl = [];


            app.ENL = [];
            
            app.NL_Table.Data = [];
            app.EL_Table.Data = [];
            app.ENL_Table.Data = [];
        end

        % Button pushed function: RhombusPlotButton
        function RhombusPlotButtonPushed(app, event)
            Void_Rhombus_Mesher(app)
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            
            switch selectedButton
                 case app.Jet_4
                    app.colorMap = turbo;
                    colormap(app.UIAxes_9,app.colorMap);
                   
                case app.Bone_4
                    app.colorMap = bone;
                    colormap(app.UIAxes_9,app.colorMap);
               
                case app.Parula_4
                    app.colorMap = parula;
                    colormap(app.UIAxes_9,app.colorMap);
                    
                case app.Autumn_4
                    app.colorMap = autumn;
                    colormap(app.UIAxes_9,app.colorMap);
                    
            end
        end

        % Value changed function: ElasticModulusPaEditField_3
        function ElasticModulusPaEditField_3ValueChanged(app, event)
            app.E = app.ElasticModulusPaEditField_3.Value;
            
        end

        % Value changed function: AreamEditField_3
        function AreamEditField_3ValueChanged(app, event)
            app.A = app.AreamEditField_3.Value;
            
        end

        % Value changed function: ofNodesEditField_2
        function ofNodesEditField_2ValueChanged(app, event)
            value = app.ofNodesEditField_2.Value;
            
        end

        % Value changed function: ofElementsEditField_2
        function ofElementsEditField_2ValueChanged(app, event)
            value = app.ofElementsEditField_2.Value;
            
        end

        % Value changed function: FORCEEditField
        function FORCEEditFieldValueChanged(app, event)
            app.Force13 = app.FORCEEditField.Value;
            
        end

        % Button pushed function: BeamExtension
        function BeamExtensionButtonPushed(app, event)
            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL((app.Uniform_c/2)+1,3) = [-1];
            app.ENL((app.Uniform_c/2)+1+(5*(app.Uniform_c+1)),3) = [-1];
            for i = 1:app.Uniform_c
                app.ENL((i*(app.Uniform_c+1)),11) = [app.Force13/(app.Uniform_c+1)];
                app.ENL(((i-1)*(app.Uniform_c+1))+1,11) = [app.Force13/(-app.Uniform_c-1)];
            end
           
            app.ENL
            
        end

        % Button pushed function: CircleExtension
        function CircleExtensionButtonPushed(app, event)

            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL((app.Global_p/2)+1,3) = [-1];
            app.ENL((app.Global_p/2)+1+(2*app.Global_p),3) = [-1];
            app.ENL((app.Global_p+1):(2*app.Global_p)+1,11) = [app.Force13/(app.Global_p+1)];
            app.ENL(((3*app.Global_p)+1):4*app.Global_p,11) = [app.Force13/(-app.Global_p-1)];
            app.ENL(1,11) = [app.Force13/(-app.Global_p-1)];
            app.ENL

           
        end

        % Button pushed function: SquareExtension
        function SquareExtensionButtonPushed(app, event)
            CircleExtensionButtonPushed(app)
        end

        % Button pushed function: RhombusExtension
        function RhombusExtensionButtonPushed(app, event)
            CircleExtensionButtonPushed(app)
        end

        % Button pushed function: CircleExpansion
        function CircleExpansionButtonPushed(app, event)
            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL(1:(app.Global_p+1),4) = [-1];
            app.ENL((app.Global_p/2)+1,3) = [-1];
            app.ENL(app.Global_p+1:(2*app.Global_p)+1,11) =  [app.Force13/(app.Global_p+1)];
            app.ENL(((2*app.Global_p)+1):(3*app.Global_p)+1,12) = [app.Force13/(app.Global_p+1)];
            app.ENL(((3*app.Global_p)+1):((4*app.Global_p)),11) = [app.Force13/(-app.Global_p-1)];
            app.ENL(1,11) = [app.Force13/(-app.Global_p-1)];
            app.ENL
        end

        % Button pushed function: SquareExpansion
        function SquareExpansionButtonPushed(app, event)
            CircleExpansionButtonPushed(app)
        end

        % Button pushed function: RhombusExpansion
        function RhombusExpansionButtonPushed(app, event)
            CircleExpansionButtonPushed(app)
        end

        % Button pushed function: CircleShear
        function CircleShearButtonPushed(app, event)
            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL(1:(app.Global_p+1),3:4) = [-1];
            app.ENL(((2*app.Global_p)+1):(3*app.Global_p)+1,11) = [app.Force13/(app.Global_p+1)];
            app.ENL(((2*app.Global_p)+1):(3*app.Global_p)+1,4) = [-1];
            app.ENL
        end

        % Button pushed function: SquareShear
        function SquareShearButtonPushed(app, event)
            CircleShearButtonPushed(app)
        end

        % Button pushed function: RhombusShear
        function RhombusShearButtonPushed(app, event)
            CircleShearButtonPushed(app)
        end

        % Button pushed function: BeamExpansion
        function BeamExpansionButtonPushed(app, event)
            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL(1:(app.Uniform_c+1),4) = [-1];
            app.ENL(((app.Uniform_c/2)+1),3) = [-1];
             for i = 1:app.Uniform_c
                app.ENL((i*(app.Uniform_c+1)),11) = [app.Force13/(app.Uniform_c+1)];
                app.ENL(((i-1)*(app.Uniform_c+1))+1,11) = [app.Force13/(-app.Uniform_c-1)];
             end
             app.ENL((app.Uniform_c*app.Uniform_c):((app.Uniform_c+1)*app.Uniform_c),12) = [app.Force13/(app.Uniform_c+1)];
             app.ENL
        end

        % Button pushed function: BeamShear
        function BeamShearButtonPushed(app, event)
            app.ENL = zeros(size(app.NL,1),12);
            app.ENL(:,1:2) = app.NL;
            app.ENL(:,3:4) = ones(size(app.NL,1),2);
            app.ENL(1:app.Uniform_c+1,3:4) = [-1];
            app.ENL((app.Uniform_c)*(app.Uniform_c):((app.Uniform_c)*(app.Uniform_c))+app.Uniform_c,4) = [-1];
            app.ENL((app.Uniform_c)*(app.Uniform_c):((app.Uniform_c)*(app.Uniform_c))+app.Uniform_c,11) = [app.Force13/(app.Uniform_c+1)];
            app.ENL
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            format long


            assign_BCs2(app); %write [enl dofsd docs]
            
            ENL3 = app.ENL(:,3);
            ENL4 = app.ENL(:,4);
            ENL3(ENL3==0)=1;
            ENL4(ENL4==0)=1;
            app.ENL(:,3) = ENL3;
            app.ENL(:,4) = ENL4;

            K = assemble_stiffness2(app);


            assemble_forces2(app);
            assemble_displacements2(app);
            
            Kpu = K(1:app.DOFs, 1:app.DOFs);
            Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

         
            F = app.Fp - Kpp*app.Up;
            
            app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
            
            app.Fu = Kuu*app.Uu + Kup*app.Up;
            
            update_nodes2(app);

            app.ENL_Table.Data = app.ENL;


            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            
            %%%%%  Draw Undeformed Configuration  %%%%%%
            

            cla(app.UIAxes_9);

            hold(app.UIAxes_9,'on');

            colormap(app.UIAxes_9,app.colorMap);


            %%%%%   Draw The Deformed Configuration   %%%%%


            %%%%%   Plot Elements With Stress Distribution   %%%%%
            
            for i=1:app.NoE

                sigmaS = app.E *(sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2+(app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                colS = [sigmaS,sigmaS];
            
                x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                z = zeros(size(x));
            
                if app.DispDrop_2 == "Total Displacement"
                    TotalDispCol = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    
                    surface(app.UIAxes_9, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol','interp','linew',4);
                
                elseif app.DispDrop_2 == "X Displacement"
                    
                    XDispCol = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                    
                    surface(app.UIAxes_9, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);
                    
                elseif app.DispDrop_2 == "Y Displacement"   
                    
                    YDispCol = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                    
                    surface(app.UIAxes_9, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);

                elseif app.DispDrop_2 == "Stress Distribution"
                    
                    surface(app.UIAxes_9,[x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol', 'interp','linew', 4);


                end


            end

            colorbar(app.UIAxes_9, "Color","w");


        end

        % Value changed function: DisplacementDropDown_2
        function DisplacementDropDown_2ValueChanged(app, event)
            app.DispDrop_2 = app.DisplacementDropDown_2.Value;

            
        end

        % Value changed function: NodeNumberEditField_2
        function NodeNumberEditField_2ValueChanged(app, event)
            app.NodeNumber13 = app.NodeNumberEditField_2.Value;
            
        end

        % Value changed function: DirectionSwitch_2
        function DirectionSwitch_2ValueChanged(app, event)
            app.BCdirectionSwitch13 = app.DirectionSwitch_2.Value;
            
        end

        % Value changed function: ForceDispSwitch_2
        function ForceDispSwitch_2ValueChanged(app, event)
            app.BCforcedisplacementSwitch13 = app.ForceDispSwitch_2.Value;
            
        end

        % Value changed function: MagnitudeEditField_2
        function MagnitudeEditField_2ValueChanged(app, event)
            app.BCvalue13 = app.MagnitudeEditField_2.Value;
            
        end

        % Button pushed function: ApplyBCButton_2
        function ApplyBCButton_2Pushed(app, event)
            assign_BCs13(app)
        end

        % Value changed function: ScoreBasedIterationSwitch
        function ScoreBasedIterationSwitchValueChanged(app, event)
            app.Score_Based = app.ScoreBasedIterationSwitch.Value;
            
        end

        % Button pushed function: HowtouseButton
        function HowtouseButtonPushed(app, event)
            Info1 = "WELCOME!";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "As a first step, you should make your structure by adding nodes and then, connection them with elements. At the bottom left corner, you can add nodes and then connect them with elements.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "Also you can click on to the 'Read NL & EL' button to directly upload your structure from an Excel file.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "In the second step, you should assign boundary conditions. At the middle bottom part, you can assign BCs. As a reminder, zero displacement BC fixes the node in that direction.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "As a last step, you can press to the RUN button to see the results. Remeber that results will be shown on the third tab which you can shift to it from left upper tab segment.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "*** You can open/close node/element indicators and number by the toggle switches that are located at the left upper corner. Also size of the shapes can be changed by discrete knob that is located under the toggle switches.";


            Info1 = compose(Info1);
            
            msgbox(Info1,"How to use?","help")
        end

        % Button pushed function: HowtouseButton_2
        function HowtouseButton_2Pushed(app, event)
            Info1 = "WELCOME TO THE SECOND PART!";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "As a first step, you should upload your bridge that you wanted to optimize by using 'Read File' button.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "In the second step, you should specify your material's properties such as density, cross-sectional area and the elastic modulus.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "You can check your bridge's weight from weight indicator screen.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "If you are ready, you can pass to the 'Optimization' tab to see what will happen to your bridge ...";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "*** You can open/close node/element indicators and number by the rocker switches that are located at the left side of the screen.";


            Info1 = compose(Info1);
            
            msgbox(Info1,"How to use?","help")
        end

        % Button pushed function: HowtouseButton_3
        function HowtouseButton_3Pushed(app, event)
            Info1 = "WELCOME TO THE THIRD AND THE FOURTH PARTS!";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "As a first step, you should select the type of structure you want to analyze. There is 4 tabs that can be selected : 'Beam with no void', 'Circle void', 'Square void', 'Rhombus void'.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "In the second step, you should specify your operation: Extansion, Expansion or Shear";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "You can also assign boundary conditions to the structure. At the right upper corner, Assign BCs box is ready to use.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "If you are ready, you can click to the 'RUN' button to see what will happen to your structure ...";

            Info1 = compose(Info1);
            
            msgbox(Info1,"How to use?","help")
        
        end

        % Button pushed function: LearnTheLogicBehindButton
        function LearnTheLogicBehindButtonPushed(app, event)
            Info1 = "HERE IS THE LOGIC BEHIND THIS OPTIMIZATION!";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "What happens if you hang weights to a chain? Chain deforms to carry the weights. If we hang more than one wweight, chain will also gets a shape accordingly. This natural behavior of structures can be considered in reverse direction. " + ...
                "If we design a bridge arc to hang the weights on its nodes, it needs a specific shape to hang the weights without falling, especially for asymmetric weight distributions. This method starts with analyzing the reversed deformed structure that user inputted. " + ...
                "The results of this deformed shape then will be reversed again to see the first iteration through the whole structure. It can bee seen that maximum deflaction in bridge will decrese with this iterations and bridge approaches to the optimized shape. This process increases the weight " + ...
                "of the bridge and the adjusted** calculation of score is used to stop the iterations. Score deceases to some point, because the increase in weight is smaller than the decrease in maximum defleaction. At some point it reaches an equilibrium. After that point, program stops the iterations and show the optimal " + ...
                "shape of the bridge.";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "REFERANCE VIDEO: https://www.youtube.com/watch?v=JlL6ZHChhQE&t=181s";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "";
            Info1 = Info1 + "\n" + "** Adjusted calculation of score is provided here for the file: Bridge_V1. For better optimizations for other types of bridges, this process should be adjusted accordingly to reach the equilibrium position.";
            Info1 = Info1 + "\n" + "Max. Deflection: abs(min(app.ENL_A(:,10)*1000000))-Modification_Multiplier";
            Info1 = Info1 + "\n" + "  x: abs(min(app.ENL_A(:,10)*1000000))     y = 100    r = mod(x,y)   Modification_Multiplier = (x-r)";     
            Info1 = Info1 + "\n" + " Score: ((abs(min(app.ENL_A(:,10)))*1000000)-Modification_Multiplier)*sum(abs(app.TotalW_A))";
            
            Info1 = compose(Info1);
            
            msgbox(Info1,"Learn The Logic Behind ...","help")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMProject1AppUIFigure = uifigure('Visible', 'off');
            app.FEMProject1AppUIFigure.Name = 'FEM Project #1 App.';
            app.FEMProject1AppUIFigure.Icon = 'unnamed.png';
            app.GridLayout = uigridlayout(app.FEMProject1AppUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MakeStructureTab = uitab(app.TabGroup);
            app.MakeStructureTab.Title = 'Make Structure';
            app.UIAxes = uiaxes(app.MakeStructureTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RUNButton = uibutton(app.MakeStructureTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NodeNumberEditFieldLabel = uilabel(app.MakeStructureTab);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.NodeNumberEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.NodeNumberEditField.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditFieldValueChanged, true);
            app.DirectionSwitchLabel = uilabel(app.MakeStructureTab);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.MakeStructureTab, 'slider');
            app.DirectionSwitch.Items = {'X', 'Y'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'X';
            app.BCTypeLabel = uilabel(app.MakeStructureTab);
            app.BCTypeLabel.Text = 'BC Type';
            app.ForceDispSwitch = uiswitch(app.MakeStructureTab, 'slider');
            app.ForceDispSwitch.Items = {'F', 'D'};
            app.ForceDispSwitch.ValueChangedFcn = createCallbackFcn(app, @ForceDispSwitchValueChanged, true);
            app.ForceDispSwitch.Value = 'D';
            app.MagnitudeEditFieldLabel = uilabel(app.MakeStructureTab);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.MagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeEditFieldValueChanged, true);
            app.ApplyBCButton = uibutton(app.MakeStructureTab, 'push');
            app.ApplyBCButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCButtonPushed, true);
            app.ApplyBCButton.Text = 'Apply BC';
            app.NodeLabelSwitch_2Label = uilabel(app.MakeStructureTab);
            app.NodeLabelSwitch_2Label.Text = 'Element Number';
            app.ElementNumberSwitch = uiswitch(app.MakeStructureTab, 'toggle');
            app.ElementNumberSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementNumberSwitchValueChanged, true);
            app.ElementNumberSwitch.Value = 'On';
            app.NodeLabelSwitchLabel = uilabel(app.MakeStructureTab);
            app.NodeLabelSwitchLabel.Text = 'Node Number';
            app.NodeNumberSwitch = uiswitch(app.MakeStructureTab, 'toggle');
            app.NodeNumberSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeNumberSwitchValueChanged, true);
            app.NodeNumberSwitch.Value = 'On';
            app.Knob = uiknob(app.MakeStructureTab, 'discrete');
            app.Knob.Items = {'Tiny', 'Small', 'Normal', 'Big', 'Huge'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Normal';
            app.EditField = uieditfield(app.MakeStructureTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'PRE-PROCESS / PROCESS';
            app.ClearStructureButton = uibutton(app.MakeStructureTab, 'push');
            app.ClearStructureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStructureButtonPushed, true);
            app.ClearStructureButton.Text = 'Clear Structure';
            app.ReadNLELButton = uibutton(app.MakeStructureTab, 'push');
            app.ReadNLELButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNLELButtonPushed, true);
            app.ReadNLELButton.Text = 'Read NL & EL';
            app.ofElementsEditFieldLabel = uilabel(app.MakeStructureTab);
            app.ofElementsEditFieldLabel.Text = {'# of Elements'; ''};
            app.ofElementsEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.ofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @ofElementsEditFieldValueChanged, true);
            app.ofElementsEditField.Editable = 'off';
            app.ofNodesEditFieldLabel = uilabel(app.MakeStructureTab);
            app.ofNodesEditFieldLabel.Text = '# of Nodes';
            app.ofNodesEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.ofNodesEditField.ValueChangedFcn = createCallbackFcn(app, @ofNodesEditFieldValueChanged, true);
            app.ofNodesEditField.Editable = 'off';
            app.GenerateNodeButton = uibutton(app.MakeStructureTab, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'Generate Node';
            app.GenerateElementButton = uibutton(app.MakeStructureTab, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.YCoordinateLabel = uilabel(app.MakeStructureTab);
            app.YCoordinateLabel.Text = 'Y Coordinate';
            app.Y_coor = uieditfield(app.MakeStructureTab, 'numeric');
            app.XCoordinateLabel = uilabel(app.MakeStructureTab);
            app.XCoordinateLabel.Text = 'X Coordinate';
            app.X_coor = uieditfield(app.MakeStructureTab, 'numeric');
            app.AddingNodeLabel = uilabel(app.MakeStructureTab);
            app.AddingNodeLabel.Text = 'Adding Node';
            app.AddingElementLabel = uilabel(app.MakeStructureTab);
            app.AddingElementLabel.Text = 'Adding Element';
            app.StartingNodeEditFieldLabel = uilabel(app.MakeStructureTab);
            app.StartingNodeEditFieldLabel.Text = 'Starting Node #';
            app.StartingNode = uieditfield(app.MakeStructureTab, 'numeric');
            app.StartingNode.Value = 1;
            app.EndingNodeEditFieldLabel = uilabel(app.MakeStructureTab);
            app.EndingNodeEditFieldLabel.Text = 'Ending Node #';
            app.EndingNode = uieditfield(app.MakeStructureTab, 'numeric');
            app.EndingNode.Value = 2;
            app.Lamp = uilamp(app.MakeStructureTab);
            app.ButtonGroup_2 = uibuttongroup(app.MakeStructureTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.Jet_2 = uitogglebutton(app.ButtonGroup_2);
            app.Jet_2.Icon = 'Rainbow.gif';
            app.Jet_2.Text = '';
            app.Jet_2.Value = true;
            app.Bone_2 = uitogglebutton(app.ButtonGroup_2);
            app.Bone_2.Icon = 'Gey.gif';
            app.Bone_2.Text = '';
            app.Parula_2 = uitogglebutton(app.ButtonGroup_2);
            app.Parula_2.Icon = 'YellowBlue.gif';
            app.Parula_2.Text = '';
            app.Autumn_2 = uitogglebutton(app.ButtonGroup_2);
            app.Autumn_2.Icon = 'Autumn.gif';
            app.Autumn_2.Text = '';
            app.Lamp_2 = uilamp(app.MakeStructureTab);
            app.ElasticModulusPaEditFieldLabel = uilabel(app.MakeStructureTab);
            app.ElasticModulusPaEditFieldLabel.Text = 'Elastic Modulus [Pa]';
            app.ElasticModulusPaEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.ElasticModulusPaEditField.ValueChangedFcn = createCallbackFcn(app, @ElasticModulusPaEditFieldValueChanged, true);
            app.ElasticModulusPaEditField.Value = 1000000;
            app.AreamEditFieldLabel = uilabel(app.MakeStructureTab);
            app.AreamEditFieldLabel.Text = 'Area [m²]';
            app.AreamEditField = uieditfield(app.MakeStructureTab, 'numeric');
            app.AreamEditField.ValueChangedFcn = createCallbackFcn(app, @AreamEditFieldValueChanged, true);
            app.AreamEditField.Value = 0.01;
            app.NodeIndicatorSwitchLabel = uilabel(app.MakeStructureTab);
            app.NodeIndicatorSwitchLabel.Text = 'Node Indicator';
            app.NodeIndicatorSwitch = uiswitch(app.MakeStructureTab, 'toggle');
            app.NodeIndicatorSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeIndicatorSwitchValueChanged, true);
            app.NodeIndicatorSwitch.Value = 'On';
            app.ElementIndicatorSwitchLabel = uilabel(app.MakeStructureTab);
            app.ElementIndicatorSwitchLabel.Text = 'Element Indicator';
            app.ElementIndicatorSwitch = uiswitch(app.MakeStructureTab, 'toggle');
            app.ElementIndicatorSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementIndicatorSwitchValueChanged, true);
            app.ElementIndicatorSwitch.Value = 'On';
            app.Lamp_3 = uilamp(app.MakeStructureTab);
            app.Lamp_4 = uilamp(app.MakeStructureTab);
            app.HowtouseButton = uibutton(app.MakeStructureTab, 'push');
            app.HowtouseButton.ButtonPushedFcn = createCallbackFcn(app, @HowtouseButtonPushed, true);
            app.HowtouseButton.Text = 'How to use?';
            app.ExtendedNodeListTab = uitab(app.TabGroup);
            app.ExtendedNodeListTab.Title = 'Extended Node List';
            app.NL_Table = uitable(app.ExtendedNodeListTab);
            app.NL_Table.ColumnName = {'X'; 'Y'};
            app.NL_Table.RowName = {};
            app.NodeListLabel = uilabel(app.ExtendedNodeListTab);
            app.NodeListLabel.Text = 'Node List';
            app.EL_Table = uitable(app.ExtendedNodeListTab);
            app.EL_Table.ColumnName = {'Starting Node'; 'Ending Node'};
            app.EL_Table.RowName = {};
            app.ElementListLabel = uilabel(app.ExtendedNodeListTab);
            app.ElementListLabel.Text = 'Element List';
            app.ExtendedNodeListLabel = uilabel(app.ExtendedNodeListTab);
            app.ExtendedNodeListLabel.Text = 'Extended Node List';
            app.ENL_Table = uitable(app.ExtendedNodeListTab);
            app.ENL_Table.ColumnName = {'Node Coordinate (x)'; 'Node Coordinate (y)'; 'BC (x)'; 'BC (y)'; 'Temp.Degree (x)'; 'Temp Degree (y)'; 'Global Deg. (x)'; 'Global Degree (y)'; 'Displacement (x)'; 'Displacement (y)'; 'Force (x)'; 'Force (y)'};
            app.ENL_Table.RowName = {};
            app.SeeResultsTab = uitab(app.TabGroup);
            app.SeeResultsTab.Title = 'See Results';
            app.UIAxes_2 = uiaxes(app.SeeResultsTab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.SeeResultsTab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ExaggerateSliderLabel = uilabel(app.SeeResultsTab);
            app.ExaggerateSliderLabel.Text = 'Exaggerate';
            app.ExaggerateSlider = uislider(app.SeeResultsTab);
            app.ExaggerateSlider.Limits = [25 5000];
            app.ExaggerateSlider.MajorTicks = [];
            app.ExaggerateSlider.MajorTickLabels = {''};
            app.ExaggerateSlider.ValueChangedFcn = createCallbackFcn(app, @ExaggerateSliderValueChanged, true);
            app.ExaggerateSlider.Value = 100;
            app.SimplifiedRealisticExaggeratedAreyouseriousLabel = uilabel(app.SeeResultsTab);
            app.SimplifiedRealisticExaggeratedAreyouseriousLabel.Text = 'Simplified        Realistic       Exaggerated       Are you serious ??     ';
            app.ButtonGroup_3 = uibuttongroup(app.SeeResultsTab);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.Jet_3 = uitogglebutton(app.ButtonGroup_3);
            app.Jet_3.Icon = 'Rainbow.gif';
            app.Jet_3.Text = '';
            app.Jet_3.Value = true;
            app.Bone_3 = uitogglebutton(app.ButtonGroup_3);
            app.Bone_3.Icon = 'Gey.gif';
            app.Bone_3.Text = '';
            app.Parula_3 = uitogglebutton(app.ButtonGroup_3);
            app.Parula_3.Icon = 'YellowBlue.gif';
            app.Parula_3.Text = '';
            app.Autumn_3 = uitogglebutton(app.ButtonGroup_3);
            app.Autumn_3.Icon = 'Autumn.gif';
            app.Autumn_3.Text = '';
            app.DisplacementDropDown = uidropdown(app.SeeResultsTab);
            app.DisplacementDropDown.Items = {'Total Displacement', 'X Displacement', 'Y Displacement'};
            app.DisplacementDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplacementDropDownValueChanged, true);
            app.DisplacementDropDown.Value = 'Total Displacement';
            app.ElementNumberSwitch_2Label = uilabel(app.SeeResultsTab);
            app.ElementNumberSwitch_2Label.Text = 'Element Number Switch';
            app.ElementNumberSwitch_2 = uiswitch(app.SeeResultsTab, 'rocker');
            app.ElementNumberSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ElementNumberSwitch_2ValueChanged, true);
            app.NodeNumberSwitch_2Label = uilabel(app.SeeResultsTab);
            app.NodeNumberSwitch_2Label.Text = 'Node Number Switch';
            app.NodeNumberSwitch_2 = uiswitch(app.SeeResultsTab, 'rocker');
            app.NodeNumberSwitch_2.ValueChangedFcn = createCallbackFcn(app, @NodeNumberSwitch_2ValueChanged, true);
            app.NodeIndicatorSwitch_2Label = uilabel(app.SeeResultsTab);
            app.NodeIndicatorSwitch_2Label.Text = 'Node Indicator Switch';
            app.NodeIndicatorSwitch_2 = uiswitch(app.SeeResultsTab, 'rocker');
            app.NodeIndicatorSwitch_2.ValueChangedFcn = createCallbackFcn(app, @NodeIndicatorSwitch_2ValueChanged, true);
            app.OldNodeIndicatorSwitchLabel = uilabel(app.SeeResultsTab);
            app.OldNodeIndicatorSwitchLabel.Text = 'Old Node Indicator';
            app.OldNodeIndicatorSwitch = uiswitch(app.SeeResultsTab, 'rocker');
            app.OldNodeIndicatorSwitch.ValueChangedFcn = createCallbackFcn(app, @OldNodeIndicatorSwitchValueChanged, true);
            app.OptimizationUserInterfaceTab = uitab(app.TabGroup);
            app.OptimizationUserInterfaceTab.Title = 'Optimization User Interface';
            app.UIAxes_4 = uiaxes(app.OptimizationUserInterfaceTab);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Button = uibutton(app.OptimizationUserInterfaceTab, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.ReadFileButton = uibutton(app.OptimizationUserInterfaceTab, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.NodeNumberSwitch_3Label = uilabel(app.OptimizationUserInterfaceTab);
            app.NodeNumberSwitch_3Label.Text = 'Node Number';
            app.NodeNumberSwitch_3 = uiswitch(app.OptimizationUserInterfaceTab, 'rocker');
            app.NodeNumberSwitch_3.ValueChangedFcn = createCallbackFcn(app, @NodeNumberSwitch_3ValueChanged, true);
            app.ElementNumberSwitch_3Label = uilabel(app.OptimizationUserInterfaceTab);
            app.ElementNumberSwitch_3Label.Text = 'Element Number';
            app.ElementNumberSwitch_3 = uiswitch(app.OptimizationUserInterfaceTab, 'rocker');
            app.ElementNumberSwitch_3.ValueChangedFcn = createCallbackFcn(app, @ElementNumberSwitch_3ValueChanged, true);
            app.NodeIndicatorSwitch_3Label = uilabel(app.OptimizationUserInterfaceTab);
            app.NodeIndicatorSwitch_3Label.Text = 'Node Indicator';
            app.NodeIndicatorSwitch_3 = uiswitch(app.OptimizationUserInterfaceTab, 'rocker');
            app.NodeIndicatorSwitch_3.ValueChangedFcn = createCallbackFcn(app, @NodeIndicatorSwitch_3ValueChanged, true);
            app.ElementIndicatorSwitch_2Label = uilabel(app.OptimizationUserInterfaceTab);
            app.ElementIndicatorSwitch_2Label.Text = 'Element Indicator';
            app.ElementIndicatorSwitch_2 = uiswitch(app.OptimizationUserInterfaceTab, 'rocker');
            app.ElementIndicatorSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ElementIndicatorSwitch_2ValueChanged, true);
            app.DensityLabel = uilabel(app.OptimizationUserInterfaceTab);
            app.DensityLabel.Text = 'Density (ρ):  ';
            app.DensityEditField = uieditfield(app.OptimizationUserInterfaceTab, 'numeric');
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Value = 3.5;
            app.AreamLabel = uilabel(app.OptimizationUserInterfaceTab);
            app.AreamLabel.Text = 'Area [m²]:';
            app.AreamEditField_2 = uieditfield(app.OptimizationUserInterfaceTab, 'numeric');
            app.AreamEditField_2.ValueChangedFcn = createCallbackFcn(app, @AreamEditField_2ValueChanged, true);
            app.AreamEditField_2.Value = 0.01;
            app.ElasticModulusPaLabel = uilabel(app.OptimizationUserInterfaceTab);
            app.ElasticModulusPaLabel.Text = 'Elastic Modulus [Pa]:';
            app.ElasticModulusPaEditField_2 = uieditfield(app.OptimizationUserInterfaceTab, 'numeric');
            app.ElasticModulusPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @ElasticModulusPaEditField_2ValueChanged, true);
            app.ElasticModulusPaEditField_2.Value = 1000000;
            app.TotalWeightLabel = uilabel(app.OptimizationUserInterfaceTab);
            app.TotalWeightLabel.Text = 'Total Weight:';
            app.TotalWeightEditField = uieditfield(app.OptimizationUserInterfaceTab, 'numeric');
            app.TotalWeightEditField.ValueChangedFcn = createCallbackFcn(app, @TotalWeightEditFieldValueChanged, true);
            app.TotalWeightEditField.Editable = 'off';
            app.HowtouseButton_2 = uibutton(app.OptimizationUserInterfaceTab, 'push');
            app.HowtouseButton_2.ButtonPushedFcn = createCallbackFcn(app, @HowtouseButton_2Pushed, true);
            app.HowtouseButton_2.Text = 'How to use?';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.UIAxes_5 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.ButtonDownFcn = createCallbackFcn(app, @UIAxes_6ButtonDown, true);
            app.UIAxes_7 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.OptimizationTab);
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.Button_3 = uibutton(app.OptimizationTab, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.MergeCloseNodesShowStressDistributionButton = uibutton(app.OptimizationTab, 'push');
            app.MergeCloseNodesShowStressDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @MergeCloseNodesShowStressDistributionButtonPushed, true);
            app.MergeCloseNodesShowStressDistributionButton.Text = 'Merge Close Nodes / Show Stress Distribution';
            app.FixHorizontalSwitchLabel = uilabel(app.OptimizationTab);
            app.FixHorizontalSwitchLabel.Text = 'Fix Horizontal';
            app.FixHorizontalSwitch = uiswitch(app.OptimizationTab, 'toggle');
            app.FixHorizontalSwitch.ValueChangedFcn = createCallbackFcn(app, @FixHorizontalSwitchValueChanged, true);
            app.FixHorizontalSwitch.Value = 'On';
            app.OptimizeBridgeButton = uibutton(app.OptimizationTab, 'push');
            app.OptimizeBridgeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeBridgeButtonPushed, true);
            app.OptimizeBridgeButton.Text = 'Optimize Bridge';
            app.IterationNumberField = uieditfield(app.OptimizationTab, 'numeric');
            app.IterationNumberField.ValueChangedFcn = createCallbackFcn(app, @IterationNumberFieldValueChanged, true);
            app.IterationNumberField.Editable = 'off';
            app.MaxIterationNumberEditFieldLabel = uilabel(app.OptimizationTab);
            app.MaxIterationNumberEditFieldLabel.Text = 'Max Iteration Number';
            app.MaxIterationNumberEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.MaxIterationNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationNumberEditFieldValueChanged, true);
            app.MaxIterationNumberEditField.Value = 100000;
            app.Lamp_5 = uilamp(app.OptimizationTab);
            app.IterationsDoneGaugeLabel = uilabel(app.OptimizationTab);
            app.IterationsDoneGaugeLabel.Text = 'Iterations Done';
            app.IterationsDoneGauge = uigauge(app.OptimizationTab, 'semicircular');
            app.IterationsDoneGauge.Limits = [0 250];
            app.IterationsDoneGauge.Value = -1;
            app.MaximumDeflectionEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.MaximumDeflectionEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumDeflectionEditFieldValueChanged, true);
            app.MaximumDeflectionEditField.Editable = 'off';
            app.MaximumDeflectionGaugeLabel = uilabel(app.OptimizationTab);
            app.MaximumDeflectionGaugeLabel.Text = 'Maximum Deflection';
            app.MaximumDeflectionGauge = uigauge(app.OptimizationTab, 'semicircular');
            app.MaximumDeflectionGauge.Limits = [0 15];
            app.MaximumDeflectionGauge.Value = -1;
            app.TotalWeightGaugeLabel = uilabel(app.OptimizationTab);
            app.TotalWeightGaugeLabel.Text = 'Total Weight';
            app.TotalWeightGauge = uigauge(app.OptimizationTab, 'semicircular');
            app.TotalWeightGauge.Limits = [15 30];
            app.TotalWeightStepbystepEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.TotalWeightStepbystepEditField.ValueChangedFcn = createCallbackFcn(app, @TotalWeightStepbystepEditFieldValueChanged, true);
            app.TotalWeightStepbystepEditField.Editable = 'off';
            app.ScoreLabel = uilabel(app.OptimizationTab);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.ScoreEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreEditFieldValueChanged, true);
            app.ScoreEditField.Editable = 'off';
            app.ScoreGauge = uigauge(app.OptimizationTab, 'semicircular');
            app.ScoreGauge.Limits = [150 250];
            app.ScoreBasedIterationSwitchLabel = uilabel(app.OptimizationTab);
            app.ScoreBasedIterationSwitchLabel.Text = 'Score Based Iteration';
            app.ScoreBasedIterationSwitch = uiswitch(app.OptimizationTab, 'toggle');
            app.ScoreBasedIterationSwitch.ValueChangedFcn = createCallbackFcn(app, @ScoreBasedIterationSwitchValueChanged, true);
            app.ScoreBasedIterationSwitch.Value = 'On';
            app.LearnTheLogicBehindButton = uibutton(app.OptimizationTab, 'push');
            app.LearnTheLogicBehindButton.ButtonPushedFcn = createCallbackFcn(app, @LearnTheLogicBehindButtonPushed, true);
            app.LearnTheLogicBehindButton.Text = 'Learn The Logic Behind';
            app.MeshedStructureAnalysisTab = uitab(app.TabGroup);
            app.MeshedStructureAnalysisTab.Title = 'Meshed Structure Analysis';
            app.UIAxes_9 = uiaxes(app.MeshedStructureAnalysisTab);
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.Button_2 = uibutton(app.MeshedStructureAnalysisTab, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.RUNButton_2 = uibutton(app.MeshedStructureAnalysisTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.NodeNumberEditField_2Label = uilabel(app.MeshedStructureAnalysisTab);
            app.NodeNumberEditField_2Label.Text = 'Node Number';
            app.NodeNumberEditField_2 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.NodeNumberEditField_2.ValueChangedFcn = createCallbackFcn(app, @NodeNumberEditField_2ValueChanged, true);
            app.DirectionSwitch_2Label = uilabel(app.MeshedStructureAnalysisTab);
            app.DirectionSwitch_2Label.Text = 'Direction';
            app.DirectionSwitch_2 = uiswitch(app.MeshedStructureAnalysisTab, 'slider');
            app.DirectionSwitch_2.Items = {'X', 'Y'};
            app.DirectionSwitch_2.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitch_2ValueChanged, true);
            app.DirectionSwitch_2.Value = 'X';
            app.BCTypeLabel_2 = uilabel(app.MeshedStructureAnalysisTab);
            app.BCTypeLabel_2.Text = 'BC Type';
            app.ForceDispSwitch_2 = uiswitch(app.MeshedStructureAnalysisTab, 'slider');
            app.ForceDispSwitch_2.Items = {'F', 'D'};
            app.ForceDispSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ForceDispSwitch_2ValueChanged, true);
            app.ForceDispSwitch_2.Value = 'D';
            app.MagnitudeEditField_2Label = uilabel(app.MeshedStructureAnalysisTab);
            app.MagnitudeEditField_2Label.Text = 'Magnitude';
            app.MagnitudeEditField_2 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.MagnitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MagnitudeEditField_2ValueChanged, true);
            app.ApplyBCButton_2 = uibutton(app.MeshedStructureAnalysisTab, 'push');
            app.ApplyBCButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCButton_2Pushed, true);
            app.ApplyBCButton_2.Text = 'Apply BC';
            app.ClearStructureButton_2 = uibutton(app.MeshedStructureAnalysisTab, 'push');
            app.ClearStructureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearStructureButton_2Pushed, true);
            app.ClearStructureButton_2.Text = 'Clear Structure';
            app.ofElementsEditField_2Label = uilabel(app.MeshedStructureAnalysisTab);
            app.ofElementsEditField_2Label.Text = {'# of Elements'; ''};
            app.ofElementsEditField_2 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.ofElementsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ofElementsEditField_2ValueChanged, true);
            app.ofElementsEditField_2.Editable = 'off';
            app.ofNodesEditField_2Label = uilabel(app.MeshedStructureAnalysisTab);
            app.ofNodesEditField_2Label.Text = '# of Nodes';
            app.ofNodesEditField_2 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.ofNodesEditField_2.ValueChangedFcn = createCallbackFcn(app, @ofNodesEditField_2ValueChanged, true);
            app.ofNodesEditField_2.Editable = 'off';
            app.ButtonGroup_4 = uibuttongroup(app.MeshedStructureAnalysisTab);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.Jet_4 = uitogglebutton(app.ButtonGroup_4);
            app.Jet_4.Icon = 'Rainbow.gif';
            app.Jet_4.Text = '';
            app.Jet_4.Value = true;
            app.Bone_4 = uitogglebutton(app.ButtonGroup_4);
            app.Bone_4.Icon = 'Gey.gif';
            app.Bone_4.Text = '';
            app.Parula_4 = uitogglebutton(app.ButtonGroup_4);
            app.Parula_4.Icon = 'YellowBlue.gif';
            app.Parula_4.Text = '';
            app.Autumn_4 = uitogglebutton(app.ButtonGroup_4);
            app.Autumn_4.Icon = 'Autumn.gif';
            app.Autumn_4.Text = '';
            app.ElasticModulusPaEditField_3Label = uilabel(app.MeshedStructureAnalysisTab);
            app.ElasticModulusPaEditField_3Label.Text = 'Elastic Modulus [Pa]';
            app.ElasticModulusPaEditField_3 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.ElasticModulusPaEditField_3.ValueChangedFcn = createCallbackFcn(app, @ElasticModulusPaEditField_3ValueChanged, true);
            app.ElasticModulusPaEditField_3.Value = 1000000;
            app.AreamEditField_3Label = uilabel(app.MeshedStructureAnalysisTab);
            app.AreamEditField_3Label.Text = 'Area [m²]';
            app.AreamEditField_3 = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.AreamEditField_3.ValueChangedFcn = createCallbackFcn(app, @AreamEditField_3ValueChanged, true);
            app.AreamEditField_3.Value = 0.01;
            app.TabGroup2 = uitabgroup(app.MeshedStructureAnalysisTab);
            app.BeamWithNoVoidTab = uitab(app.TabGroup2);
            app.BeamWithNoVoidTab.Title = 'Beam With No Void';
            app.BeamPlotButton = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamPlotButton.ButtonPushedFcn = createCallbackFcn(app, @BeamPlotButtonPushed, true);
            app.BeamPlotButton.Icon = 'IMG_0295.jpg';
            app.BeamPlotButton.Text = '';
            app.BeamExtension = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamExtension.ButtonPushedFcn = createCallbackFcn(app, @BeamExtensionButtonPushed, true);
            app.BeamExtension.Icon = 'IMG_0292.jpg';
            app.BeamExtension.Text = '';
            app.BeamExpansion = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamExpansion.ButtonPushedFcn = createCallbackFcn(app, @BeamExpansionButtonPushed, true);
            app.BeamExpansion.Icon = 'IMG_0293.jpg';
            app.BeamExpansion.Text = '';
            app.BeamShear = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamShear.ButtonPushedFcn = createCallbackFcn(app, @BeamShearButtonPushed, true);
            app.BeamShear.Icon = 'IMG_0294.jpg';
            app.BeamShear.Text = '';
            app.aEditFieldLabel_4 = uilabel(app.BeamWithNoVoidTab);
            app.aEditFieldLabel_4.Text = 'a :';
            app.Beam_aEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_aEditField.Value = 1;
            app.bEditFieldLabel_4 = uilabel(app.BeamWithNoVoidTab);
            app.bEditFieldLabel_4.Text = 'b:';
            app.Beam_bEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_bEditField.Value = 0.2;
            app.cEditFieldLabel_4 = uilabel(app.BeamWithNoVoidTab);
            app.cEditFieldLabel_4.Text = 'c:';
            app.Beam_cEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_cEditField.Value = 6;
            app.dEditFieldLabel_4 = uilabel(app.BeamWithNoVoidTab);
            app.dEditFieldLabel_4.Text = 'd:';
            app.Beam_dEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_dEditField.Value = 5;
            app.CircleTab = uitab(app.TabGroup2);
            app.CircleTab.Title = 'Circle';
            app.CirclePlotButton = uibutton(app.CircleTab, 'push');
            app.CirclePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CirclePlotButtonPushed, true);
            app.CirclePlotButton.Icon = 'IMG_0289.jpg';
            app.CirclePlotButton.Text = '';
            app.aEditFieldLabel = uilabel(app.CircleTab);
            app.aEditFieldLabel.Text = 'a :';
            app.Circle_aEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.CircleTab);
            app.bEditFieldLabel.Text = 'b:';
            app.Circle_bEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_bEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.CircleTab);
            app.cEditFieldLabel.Text = 'c:';
            app.Circle_cEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_cEditField.Value = 6;
            app.dEditFieldLabel = uilabel(app.CircleTab);
            app.dEditFieldLabel.Text = 'd:';
            app.Circle_dEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_dEditField.Value = 5;
            app.rEditFieldLabel = uilabel(app.CircleTab);
            app.rEditFieldLabel.Text = 'r:';
            app.Circle_rEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_rEditField.Value = 0.2;
            app.CircleExtension = uibutton(app.CircleTab, 'push');
            app.CircleExtension.ButtonPushedFcn = createCallbackFcn(app, @CircleExtensionButtonPushed, true);
            app.CircleExtension.Icon = 'IMG_0292.jpg';
            app.CircleExtension.Text = '';
            app.CircleExpansion = uibutton(app.CircleTab, 'push');
            app.CircleExpansion.ButtonPushedFcn = createCallbackFcn(app, @CircleExpansionButtonPushed, true);
            app.CircleExpansion.Icon = 'IMG_0293.jpg';
            app.CircleExpansion.Text = '';
            app.CircleShear = uibutton(app.CircleTab, 'push');
            app.CircleShear.ButtonPushedFcn = createCallbackFcn(app, @CircleShearButtonPushed, true);
            app.CircleShear.Icon = 'IMG_0294.jpg';
            app.CircleShear.Text = '';
            app.SquareTab = uitab(app.TabGroup2);
            app.SquareTab.Title = 'Square';
            app.SquarePlotButton = uibutton(app.SquareTab, 'push');
            app.SquarePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SquarePlotButtonPushed, true);
            app.SquarePlotButton.Icon = 'IMG_0290.jpg';
            app.SquarePlotButton.Text = '';
            app.SquareExtension = uibutton(app.SquareTab, 'push');
            app.SquareExtension.ButtonPushedFcn = createCallbackFcn(app, @SquareExtensionButtonPushed, true);
            app.SquareExtension.Icon = 'IMG_0292.jpg';
            app.SquareExtension.Text = '';
            app.SquareExpansion = uibutton(app.SquareTab, 'push');
            app.SquareExpansion.ButtonPushedFcn = createCallbackFcn(app, @SquareExpansionButtonPushed, true);
            app.SquareExpansion.Icon = 'IMG_0293.jpg';
            app.SquareExpansion.Text = '';
            app.SquareShear = uibutton(app.SquareTab, 'push');
            app.SquareShear.ButtonPushedFcn = createCallbackFcn(app, @SquareShearButtonPushed, true);
            app.SquareShear.Icon = 'IMG_0294.jpg';
            app.SquareShear.Text = '';
            app.aEditFieldLabel_2 = uilabel(app.SquareTab);
            app.aEditFieldLabel_2.Text = 'a :';
            app.Square_aEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_aEditField.Value = 1;
            app.bEditFieldLabel_2 = uilabel(app.SquareTab);
            app.bEditFieldLabel_2.Text = 'b:';
            app.Square_bEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_bEditField.Value = 1;
            app.cEditFieldLabel_2 = uilabel(app.SquareTab);
            app.cEditFieldLabel_2.Text = 'c:';
            app.Square_cEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_cEditField.Value = 6;
            app.dEditFieldLabel_2 = uilabel(app.SquareTab);
            app.dEditFieldLabel_2.Text = 'd:';
            app.Square_dEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_dEditField.Value = 5;
            app.eLabel = uilabel(app.SquareTab);
            app.eLabel.Text = 'e:';
            app.Square_eEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_eEditField.Value = 0.2;
            app.RhombusTab = uitab(app.TabGroup2);
            app.RhombusTab.Title = 'Rhombus';
            app.RhombusPlotButton = uibutton(app.RhombusTab, 'push');
            app.RhombusPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RhombusPlotButtonPushed, true);
            app.RhombusPlotButton.Icon = 'IMG_0291.jpg';
            app.RhombusPlotButton.Text = '';
            app.RhombusExtension = uibutton(app.RhombusTab, 'push');
            app.RhombusExtension.ButtonPushedFcn = createCallbackFcn(app, @RhombusExtensionButtonPushed, true);
            app.RhombusExtension.Icon = 'IMG_0292.jpg';
            app.RhombusExtension.Text = '';
            app.RhombusExpansion = uibutton(app.RhombusTab, 'push');
            app.RhombusExpansion.ButtonPushedFcn = createCallbackFcn(app, @RhombusExpansionButtonPushed, true);
            app.RhombusExpansion.Icon = 'IMG_0293.jpg';
            app.RhombusExpansion.Text = '';
            app.RhombusShear = uibutton(app.RhombusTab, 'push');
            app.RhombusShear.ButtonPushedFcn = createCallbackFcn(app, @RhombusShearButtonPushed, true);
            app.RhombusShear.Icon = 'IMG_0294.jpg';
            app.RhombusShear.Text = '';
            app.aEditFieldLabel_3 = uilabel(app.RhombusTab);
            app.aEditFieldLabel_3.Text = 'a :';
            app.Rhombus_aEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_aEditField.Value = 1;
            app.bEditFieldLabel_3 = uilabel(app.RhombusTab);
            app.bEditFieldLabel_3.Text = 'b:';
            app.Rhombus_bEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_bEditField.Value = 1;
            app.cEditFieldLabel_3 = uilabel(app.RhombusTab);
            app.cEditFieldLabel_3.Text = 'c:';
            app.Rhombus_cEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_cEditField.Value = 6;
            app.dEditFieldLabel_3 = uilabel(app.RhombusTab);
            app.dEditFieldLabel_3.Text = 'd:';
            app.Rhombus_dEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_dEditField.Value = 5;
            app.eLabel_2 = uilabel(app.RhombusTab);
            app.eLabel_2.Text = 'e:';
            app.Rhombus_eEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_eEditField.Value = 0.2;
            app.FORCELabel = uilabel(app.MeshedStructureAnalysisTab);
            app.FORCELabel.Text = 'FORCE:';
            app.FORCEEditField = uieditfield(app.MeshedStructureAnalysisTab, 'numeric');
            app.FORCEEditField.ValueChangedFcn = createCallbackFcn(app, @FORCEEditFieldValueChanged, true);
            app.FORCEEditField.Value = 500;
            app.DisplacementDropDown_2 = uidropdown(app.MeshedStructureAnalysisTab);
            app.DisplacementDropDown_2.Items = {'Total Displacement', 'X Displacement', 'Y Displacement', 'Stress Distribution'};
            app.DisplacementDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplacementDropDown_2ValueChanged, true);
            app.DisplacementDropDown_2.Value = 'Total Displacement';
            app.HowtouseButton_3 = uibutton(app.MeshedStructureAnalysisTab, 'push');
            app.HowtouseButton_3.ButtonPushedFcn = createCallbackFcn(app, @HowtouseButton_3Pushed, true);
            app.HowtouseButton_3.Text = 'How to use?';
            % Show the figure after all components are created
            app.FEMProject1AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMProject1AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMProject1AppUIFigure)
        end
    end
end